classdef UR_EAR_2022a < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                      matlab.ui.Figure
		DownloadfromcontrolsLabel     matlab.ui.control.Label
		PlayDownloadSamplerateEditField  matlab.ui.control.NumericEditField
		PlayDownloadSamplerateEditFieldLabel  matlab.ui.control.Label
		PlayDownloadSoundsLabel       matlab.ui.control.Label
		C1Label                       matlab.ui.control.Label
		C2Label                       matlab.ui.control.Label
		ClickNarrowLabel              matlab.ui.control.Label
		ClickWideLabel                matlab.ui.control.Label
		StandardPlotsLabel            matlab.ui.control.Label
		WidePlotsLabel                matlab.ui.control.Label
		UREARLabel                    matlab.ui.control.Label
		SupportLabel                  matlab.ui.control.Label
		aLabel                        matlab.ui.control.Label
		Label_3                       matlab.ui.control.Label
		ContactButton                 matlab.ui.control.Button
		FAQButton                     matlab.ui.control.Button
		ManualButton                  matlab.ui.control.Button
		Label_2                       matlab.ui.control.Label
		Label                         matlab.ui.control.Label
		WidePlotsPanel                matlab.ui.container.Panel
		Panel                         matlab.ui.container.Panel
		QuickPlotofANICatoneCFPanel   matlab.ui.container.Panel
		PlotButton                    matlab.ui.control.Button
		CFHzEditField                 matlab.ui.control.NumericEditField
		CFHzEditFieldLabel            matlab.ui.control.Label
		HoldButton                    matlab.ui.control.StateButton
		HelpButton                    matlab.ui.control.Button
		ResetButton                   matlab.ui.control.Button
		secLabel                      matlab.ui.control.Label
		toEditField                   matlab.ui.control.NumericEditField
		toEditFieldLabel              matlab.ui.control.Label
		AnalysisWindowEditField       matlab.ui.control.NumericEditField
		AnalysisWindowEditFieldLabel  matlab.ui.control.Label
		RunPanel                      matlab.ui.container.Panel
		PlotstoshowPanel              matlab.ui.container.Panel
		WideCheckBox                  matlab.ui.control.CheckBox
		StandardCheckBox              matlab.ui.control.CheckBox
		AutoscrollButtonGroup         matlab.ui.container.ButtonGroup
		toWidePlotsButton             matlab.ui.control.RadioButton
		toStandardPlotsButton         matlab.ui.control.RadioButton
		OffButton                     matlab.ui.control.RadioButton
		RunButton                     matlab.ui.control.Button
		ModelParametersPanel          matlab.ui.container.Panel
		StimulusParametersPanel       matlab.ui.container.Panel
		StimulusTypeDropDown          matlab.ui.control.DropDown
		StimulusTypeDropDownLabel     matlab.ui.control.Label
	end

	% This app requires the following files:
	% 	stimuli package in folder +stimuli
	% 	ffGn_ur_ear.m
	% 	fitaudiogram2.m
	% 	generate_neurogram_UREAR2.m
	% 	generateANpopulation.m
	% 	get_alpha_norm.m
	% 	model.m
	% 	SFIE_BE_BS_BMF.m
	% 	unitgain_bpFilter.m
	% 	model_IHC_BEZ2018.m
	% 	model_Synapse_BEZ2018.m
	% The following MEX function are OS-specific:
	% 	model_IHC_BEZ2018.mexa64 (GNU/Linux)
	% 	model_IHC_BEZ2018.mexmaci64 (macOS)
	% 	model_IHC_BEZ2018.mexw64 (Windows)
	% 	model_IHC.mexa64 (GNU/Linux)
	% 	model_IHC.mexmaci64 (macOS)
	% 	model_IHC.mexw64 (Windows)
	% 	model_Synapse_BEZ2018.mexa64 (GNU/Linux)
	% 	model_Synapse_BEZ2018.mexmaci64 (macOS)
	% 	model_Synapse_BEZ2018.mexw64 (Windows)
	% 	model_Synapse.mexa64 (GNU/Linux)
	% 	model_Synapse.mexmaci64 (macOS)
	% 	model_Synapse.mexw64 (Windows)
	% Some data files:
	%	THRESHOLD_ALL_CAT.mat
	%	THRESHOLD_ALL_HM_GM.mat
	%	THRESHOLD_ALL_HM_Shera.mat
	% The default sounds:
	% 	m06ae.wav
	% 	m06iy.wav
	% Some images for the GUI:
	%	email_icon.png
	%	pause_icon.png
	%	play_icon.png
	%	export_icon.png

	properties (Access = private)
		is_matlab = false;
		is_standalone = false;
		is_webapp = false;
		mypath
		stimData % Description
		stimIdx = 1;
		show_freqs
		freqs_changed
		parse_TIN_BW
		Fs = 100e3; % samples/sec
		RsFs = 10e3;  % resample rate for time_freq surface plots
		Pref = 20e-6; % reference pressure in pascals
		% 		Waitbar
		Progressbar
		Abort
		Which_AN = 1;
		Which_IC = 2;
		CFs
		DefaultNumCFs = 50;
		BMF
		aw_t = [0 0.04];
		selection_line_color = [0.75 0.75 0.75]; % color of Analysis Window selection lines.
		colors = {[0 0.7 0],[0 0 1],[1 0 0]}; % GBR
		be_bs_selection = 1; % 1 => BE, 2 => BS; initial selection for which IC model avg. rate plot to show.
		Condition
		stim_wav_line
		selected_line
		move_both
		analysis_window_lines
		analysis_window_hold = false;
		specgram_surf
		spec_line
		AN_surf_n
		IC_surf_n
		be_bs_radio
		wav_axes_n			matlab.ui.control.UIAxes
		specgram_axes_n		matlab.ui.control.UIAxes
		spec_axes_n			matlab.ui.control.UIAxes
		AN_axes_n			matlab.ui.control.UIAxes
		AN_model_cb
		IC_axes_n			matlab.ui.control.UIAxes
		AN_avg_axes			matlab.ui.control.UIAxes
		AN_avg_lines
		CN_avg_axes			matlab.ui.control.UIAxes
		CN_avg_lines
		IC_avg_axes			matlab.ui.control.UIAxes
		IC_avg_lines
		num_fibers_label
		AN_model_popup
		be_bs_radio_group
		CFEditLo_label
		CFEditLo
		CFEditHi_label
		CFEditHi
		numCFEdit
		num_fibers_edit
		OHCEdit
		nconditions
		speciesTypePopup
		spontTypePopup
		CF_range
		IC_data_edit
		IC_model_popup
		IC_data_labels
		IC_model_credits
		analysis_window_text
		clicks_wave_n = 0;   % Stimulus waveform plot
		clicks_specgram_n = 0;  % Spectrogram plot
		clicks_spec_n = 0;       % Spectrum CF range
		clicks_an_timefreq = 0;  % AN time_freq plot
		clicks_ic_timefreq = 0;  % IC time_freq plot
		clicks_ic_avg_rate = 0;   % For toggling between IC BP and IC BS
		clicks_wave_w = 0;
		clicks_specgram_w = 0;
		clicks_vihc_timefreq = 0;  % AN time_freq plot
		clicks_an_timefreq_w = 0;
		clicks_ic_timefreq_w = 0;
		clicks_wide = 0;
		IC_model_cb
		wav_axes_w			matlab.ui.control.UIAxes
		stim_wav_line_w
		specgram_axes_w		matlab.ui.control.UIAxes
		specgram_surf_w
		VIHC_axes_w			matlab.ui.control.UIAxes
		VIHC_surf_w
		VIHC_cb
		AN_axes_w			matlab.ui.control.UIAxes
		AN_surf_w
		AN_model_cb_w
		IC_axes_w			matlab.ui.control.UIAxes
		IC_surf_w
		IC_model_cb_w
		HTMLC1
		HTMLC2
		players
		media
		export_waveforms
		AudioFileFolder
		AudioFileCounter = 1;
		C1FileName = '';
		C2FileName = '';
		UIAxesDefaultVisibility = 'off';
		% setInteractivity = @enableDefaultInteractivity;
		setInteractivity = @disableDefaultInteractivity;
		ParPool % parallel pool
		UseParallel = true;
		DurMax
	end

	methods (Access = private)

		function createStimData(app)
			% Define the contents of each stimulus parameter panel.  There will
			% be a panel for each stimulus type, only one of which will be
			% visible at a time.

			% Make a structure array, one element per stim type.
			% Each structure element will have the following fields:
			%	Type = stimulus type string (taken from stimTypeOptions above)
			%	Description1 = description of condition 1 (if any)
			%	Description2 = description of condition 2 (if any)
			%	Panel = graphics handle to panel for that stimulus type
			%	UseLogAxes = whether results will be plotted on a log scale (T/F)
			%	NumParams = number of parameters for that stimulus type
			%	Params = structure array, one element per parameter with fields:
			%		Row = row # placement on panel, integer from 1 (top row) to NumParams
			%		Style = inferred from Value, but can also be 'radiobutton'
			%		Label = label string for param
			%		Value = numeric, logical, popupmenu index, or string(?)
			%		Choices = cell array of strings for popupmenu, empty for others
			%		LabelH graphics handle to label (if any)
			%		ValueH graphics handle to value control
			%		Callback = callback function for ValueH (if any)
			%		ButtonDownFcn = ButtonDownFcn for ValueH (if any)
			%		Tooltip = tool tip with any explanatory text
			%		Data = anything
			%       Button = auxiliary button (if any)
			%       Waveform = 2-element cell array, {wav,fs} (audio file only)
			%
			%	The Type and Panel fields are assigned automatically as are the Row number
			%	and graphic handles in the Params structure array.
			%
			% 	(Note: checkboxes have no separate label; use label text for
			% 	String property of uicheckbox.)
			%
			%	The Params structure fields Callback and ButtonDownFcn are provided in case
			%	some code needs to be run when the user changes the parameter, such as
			%	changing the content of another parameter.  They won't be needed for most
			%	stimuli where it is only necessary to collect some simple values.

			% Initialize structure array with all information about parameters.
			app.stimData = struct('Type',app.StimulusTypeDropDown.Items,...
				'Description1','',...
				'Description2','',...
				'Description1H',[],...
				'Description2H',[],...
				'Panel',[],...
				'UseLogAxes',true,... % usual case so use as default
				'NumParams',0,...
				'Params',[]);

			% Anonymous function to initialize one Params field.
			init_params_struct = @(s,id)struct('Row',num2cell(1:s(id).NumParams),...
				'Style','','Label',[],'Value',[],'Choices',[],...
				'LabelH',[],'ValueH',[],'Callback','','ButtonDownFcn','',...
				'Tooltip','','Data',[],'Button',[],'Waveform',[]);

			% Fill in information about parameters for each stimulus type.
			%-------------------------------------------------------------

			% Audio file.
			id = 1;
			initial_file1 = 'm06ae.wav';
			initial_file2 = 'm06iy.wav';
			app.stimData(id).Description1 = initial_file1;
			app.stimData(id).Description2 = initial_file2;
			app.stimData(id).NumParams = 5;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(1).Callback = @process_sound_level_entry;
			app.stimData(id).Params(1).Tooltip = 'If blank, do not scale sound level';
			app.stimData(id).Params(2).Label = 'File 1';
			app.stimData(id).Params(2).Value = initial_file1;
			app.stimData(id).Params(2).Data = initial_file1;
			app.stimData(id).Params(2).Callback = {@process_audio_file_entry,2};
			app.stimData(id).Params(2).Button = {'Upload…',60,{@app.upload,1}};
			[app.stimData(id).Params(2).Waveform{1:2}] = audioread(initial_file1);
			app.stimData(id).Params(2).Waveform{1} = ...
				mean(app.stimData(id).Params(2).Waveform{1},2);
			app.stimData(id).Params(3).Label = 'File 2';
			app.stimData(id).Params(3).Value = initial_file2;
			app.stimData(id).Params(3).Data = initial_file2;
			app.stimData(id).Params(3).Callback = {@process_audio_file_entry,3};
			app.stimData(id).Params(3).Button = {'Upload…',60,{@app.upload,2}};
			[app.stimData(id).Params(3).Waveform{1:2}] = audioread(initial_file2);
			app.stimData(id).Params(3).Waveform{1} = ...
				mean(app.stimData(id).Params(3).Waveform{1},2);
			app.stimData(id).Params(4).Label = 'LTASS SNR (dB)';
			app.stimData(id).Params(4).Value = -Inf;
			app.stimData(id).Params(4).Tooltip = 'If sound level is not specified, enter noise level in dB SPL';
			app.stimData(id).Params(5).Style = 'widelabel';
			app.stimData(id).Params(5).Label = sprintf(['Note: It is recommended ',...
				'that uploaded files not\nexceed a few seconds in length.']);
			function process_sound_level_entry(hh,~)
				if isempty(hh.Value)
					app.stimData(1).Params(4).LabelH.Text = 'LTASS level (dB SPL)';
				else
					app.stimData(1).Params(4).LabelH.Text = 'LTASS SNR (dB)';
				end
			end
			function process_audio_file_entry(hh,~,k)
				% Get string.
				str = hh.Value;

				% Clear Waveform property if field is empty.
				if isempty(str)
					app.stimData(1).Params(k).Waveform = [];
					app.stimData(1).Params(k).Value = '';
					app.stimData(1).Params(k).Data = '';
					return
				end

				% Check that file exists.
				if ~isfile(fullfile(app.mypath,str))
					hh.Value = app.stimData(1).Params(k).Value;
					uialert(app.UIFigure,'File does not exist.','Error')
					return
				end

				% Make full path to file.
				filename = fullfile(app.mypath,str);

				% Copy typed entry to Data and Description.
				app.stimData(1).Params(k).Value = str;
				app.stimData(1).Params(k).Data = filename;
				switch k
					case 2
						app.stimData(1).Description1 = str;
						app.stimData(1).Description1H.Text = str;
					case 3
						app.stimData(1).Description2 = str;
						app.stimData(1).Description2H.Text = str;
				end

				% Load file.
				[app.stimData(1).Params(k).Waveform{1:2}] = audioread(filename);
				app.stimData(1).Params(k).Waveform{1} = ...
					mean(app.stimData(1).Params(k).Waveform{1},2);
			end

			% Noise Band.
			id = 2;
			app.stimData(id).Description1 = 'Noise band';
			app.stimData(id).Description2 = 'N/A';
			app.stimData(id).NumParams = 5;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.5;
			app.stimData(id).Params(3).Label = 'Ramp Dur (s)';
			app.stimData(id).Params(3).Value = 0.02;
			app.stimData(id).Params(4).Label = 'Low Freq Cutoff (Hz)';
			app.stimData(id).Params(4).Value = 100;
			app.stimData(id).Params(5).Label = 'High Freq Cutoff (Hz)';
			app.stimData(id).Params(5).Value = 800;

			% Notched Noise.
			id = 3;
			app.stimData(id).Description1 = 'With tone';
			app.stimData(id).Description2 = 'Without tone';
			app.stimData(id).NumParams = 7;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Noise level (db SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.6;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.1;
			app.stimData(id).Params(4).Label = 'Center frequency (Hz)';
			app.stimData(id).Params(4).Value = 1500;
			app.stimData(id).Params(5).Label = sprintf('%c = CF%cnotch_width/2',916,215); % \Delta, \times
			app.stimData(id).Params(5).Value = 0.1;
			app.stimData(id).Params(6).Label = 'Bandwidth/CF';
			app.stimData(id).Params(6).Value = 0.8;
			app.stimData(id).Params(7).Label = 'Tone level (dB SPL)';
			app.stimData(id).Params(7).Value = 50;

			% Tone in Noise.
			id = 4;
			app.stimData(id).Description1 = 'Tone in noise';
			app.stimData(id).Description2 = 'Noise alone';
			app.stimData(id).NumParams = 7;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Duration (s)';
			app.stimData(id).Params(1).Value = 0.3;
			app.stimData(id).Params(1).Callback = @compute_SNR;
			app.stimData(id).Params(2).Label = 'Ramp duration (s)';
			app.stimData(id).Params(2).Value = 0.01;
			app.stimData(id).Params(2).Callback = @compute_SNR;
			app.stimData(id).Params(3).Label = 'Tone frequency (Hz)';
			app.stimData(id).Params(3).Value = 500;
			app.stimData(id).Params(3).Callback = @compute_SNR;
			app.stimData(id).Params(4).Label = 'Noise spectrum level';
			app.stimData(id).Params(4).Value = 40;
			app.stimData(id).Params(4).Callback = @compute_SNR;
			app.stimData(id).Params(5).Label = sprintf('E%c/N%c (dB)',8347,8320); % sub s, sub zero
			app.stimData(id).Params(5).Value = 15;
			app.stimData(id).Params(5).Callback = @compute_SNR;
			app.stimData(id).Params(6).Label = 'SNR, computed (dB)';
			app.stimData(id).Params(6).Value = NaN;
			app.stimData(id).Params(7).Label = 'Noise BW (Hz or octave fraction)';
			app.stimData(id).Params(7).Value = '1/3';
			app.stimData(id).Params(7).Data = struct('BWType','octaves','Value',1/3);
			app.stimData(id).Params(7).Callback = @parse_TIN_BW;
			fmtspec = 'Enter two frequencies, e.g., "100 3000" or\nfraction of an octave with a slash, e.g., "1/3".';
			app.stimData(id).Params(7).Tooltip = sprintf(fmtspec);
			function parse_TIN_BW(~,~)
				% Determine if the entry consists of two frequencies or a fraction.
				params = app.stimData(4).Params;
				str = params(7).ValueH.Value;
				% If, after removing spaces, str can be read with '%f/%f' and
				% has exactly two values, then those values are the numerator
				% and denominator of a fraction.  If that doesn't work, try to
				% read str with '%f%f'.  If that results in exactly two values,
				% then those values must be the band edges.
				result = sscanf(strrep(str,' ',''),'%f/%f');
				if length(result) == 2
					value = result(1)/result(2);
					params(7).ValueH.Value = sprintf('%g/%g',result(1),result(2));
					app.stimData(4).Params(7).Data = struct('BWType','octaves','Value',value);
				else
					result = sscanf(str,'%f%f');
					if length(result) == 2
						value = sort(result).';
						params(7).ValueH.Value = sprintf('%g %g',value);
						app.stimData(4).Params(7).Data = struct('BWType','hertz','Value',value);
					else
						uialert(app.UIFigure,['Incorrect entry. Enter two ',...
							'values or a fraction.'],'Noise BW Value Error')
						return
					end
				end
				compute_SNR()
			end
			function compute_SNR(~,~)
				% Compute SNR based on typed entries.
				params = app.stimData(4).Params;
				dur = str2double(params(1).ValueH.Value);
				rampdur = str2double(params(2).ValueH.Value);
				freq = str2double(params(3).ValueH.Value);
				N0 = str2double(params(4).ValueH.Value);
				Es_N0 = str2double(params(5).ValueH.Value);
				switch app.stimData(4).Params(7).Data.BWType
					case 'octaves'
						bw_freqs = freq.*2.^([-0.5 0.5]*params(7).Data.Value);
					case 'hertz'
						bw_freqs = params(7).Data.Value;
				end
				bin_mode = 1; % for monaural stimulus, bin_mode = 1

				% Just calculate SNR.
				calculate_SNR_only = true;
				TINstruct = stimuli.TIN(dur,rampdur,freq,N0,Es_N0,bin_mode,bw_freqs,app.Fs,calculate_SNR_only);
				% Update SNR.
				app.stimData(4).Params(6).ValueH.Value = sprintf('%.1f',TINstruct.SNR);
			end
			app.parse_TIN_BW = @parse_TIN_BW;

			% Profile Analysis.
			id = 5;
			app.stimData(id).Description1 = 'With increment';
			app.stimData(id).Description2 = 'Without increment';
			app.stimData(id).NumParams = 5;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.5;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.01;
			app.stimData(id).Params(4).Label = '# of components (must be odd)';
			app.stimData(id).Params(4).Value = 11;
			app.stimData(id).Params(4).Callback = @check_num_components;
			app.stimData(id).Params(5).Label = 'Increment (dB)';
			app.stimData(id).Params(5).Value = -2;
			function check_num_components(~,~)
				% Determine if the entry is even or odd.
				params = app.stimData(5).Params;
				value = str2double(params(4).ValueH.Value);
				if ~rem(value,2)
					new_value = value + 1;
					app.stimData(5).Params(4).Value = new_value;
					app.stimData(5).Params(4).ValueH.Value = sprintf('%g',new_value);
					uialert(app.UIFigure,'Number of components must be odd.','Error')
					return
				end
			end


			% Pinna Cues.
			id = 6;
			app.stimData(id).Description1 = 'With pinna notch';
			app.stimData(id).Description2 = 'N/A';
			app.stimData(id).NumParams = 4;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 40;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.5;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.01;
			app.stimData(id).Params(4).Label = 'Notch freq. (Hz) "Adjust CFs!"';
			app.stimData(id).Params(4).Value = 7000;

			% SAM Tone.
			id = 7;
			app.stimData(id).Description1 = 'Modulated';
			app.stimData(id).Description2 = 'Unmodulated';
			app.stimData(id).NumParams = 6;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.5;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.01;
			app.stimData(id).Params(4).Label = 'Carrier frequency (Hz)';
			app.stimData(id).Params(4).Value = 1500;
			app.stimData(id).Params(5).Label = 'Modulation frequency (Hz)';
			app.stimData(id).Params(5).Value = 100;
			app.stimData(id).Params(6).Label = 'Modulation depth (dB)';
			app.stimData(id).Params(6).Value = 0;

			% Complex Tone.
			id = 8;
			app.stimData(id).Description1 = 'Complex tone';
			app.stimData(id).Description2 = 'N/A';
			app.stimData(id).UseLogAxes = false;
			app.stimData(id).NumParams = 8;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.5;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.1;
			app.stimData(id).Params(4).Label = sprintf('F%c (Hz)',8320); % sub 0
			app.stimData(id).Params(4).Value = 200;
			app.stimData(id).Params(5).Label = 'Number of components';
			app.stimData(id).Params(5).Value = 15;
			app.stimData(id).Params(6).Label = 'Include fundamental';
			app.stimData(id).Params(6).Value = true;
			app.stimData(id).Params(7).Label = 'Filter type';
			app.stimData(id).Params(7).Choices = {'None','Low-pass','High-pass','Band-pass','Band-reject'};
			app.stimData(id).Params(7).Value = 1;
			app.stimData(id).Params(7).Callback = @show_freqs;
			app.stimData(id).Params(8).Label = 'Cutoff frequencies (Hz)';
			app.stimData(id).Params(8).Value = 100;
			app.stimData(id).Params(8).Data = {0,100,100,[100 3000],[100 3000]};
			app.stimData(id).Params(8).Callback = @freqs_changed;
			function show_freqs(hh,~)
				% Make cutoff frequencies item visible or invisible, as needed.
				if hh.Value == 1
					app.stimData(8).Params(8).LabelH.Visible = 'off';
					app.stimData(8).Params(8).ValueH.Visible = 'off';
				else
					app.stimData(8).Params(8).LabelH.Visible = 'on';
					app.stimData(8).Params(8).ValueH.Visible = 'on';
					freqs = app.stimData(8).Params(8).Data{hh.Value};
					app.stimData(8).Params(8).Value = freqs;
					app.stimData(8).Params(8).ValueH.Value = strjoin(compose('%g',freqs));
				end
			end
			function freqs_changed(hh,~)
				popup_val = app.stimData(8).Params(7).ValueH.Value;
				freqs = sscanf(hh.Value,'%f').';
				freqs0 = app.stimData(8).Params(8).Data{popup_val};
				num_freqs = length(freqs);
				num_freqs0 = length(freqs0);
				if num_freqs ~= num_freqs0
					switch num_freqs0
						case 1
							str = 'one cutoff frequency';
						case 2
							str = 'two cutoff frequencies';
					end
					msg = sprintf('You must enter exactly %s.',str);
					uialert(app.UIFigure,msg,'Input Error')
					app.stimData(8).Params(8).ValueH.Value = ...
						strjoin(compose('%g',freqs0));
					return
				end
				freqs = sort(freqs);
				app.stimData(8).Params(8).Value = freqs;
				app.stimData(8).Params(8).ValueH.Value = strjoin(compose('%g',freqs));
				app.stimData(8).Params(8).Data{popup_val} = freqs;
			end
			app.show_freqs = @show_freqs;
			app.freqs_changed = @freqs_changed;

			% Single Formant.
			id = 9;
			app.stimData(id).Description1 = 'Single formant';
			app.stimData(id).Description2 = 'N/A';
			app.stimData(id).UseLogAxes = false;
			app.stimData(id).NumParams = 6;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.3;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.025;
			app.stimData(id).Params(4).Label = 'Peak frequency (Hz)';
			app.stimData(id).Params(4).Value = 2000;
			app.stimData(id).Params(5).Label = sprintf('F%c (Hz)',8320); % sub 0
			app.stimData(id).Params(5).Value = 200;
			app.stimData(id).Params(6).Label = 'G, spectral slope (dB/octave)';
			app.stimData(id).Params(6).Value = 200;

			% Double Formant.
			id = 10;
			app.stimData(id).Description1 = 'Double formant';
			app.stimData(id).Description2 = 'N/A';
			app.stimData(id).UseLogAxes = false;
			app.stimData(id).NumParams = 6;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.3;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.025;
			app.stimData(id).Params(4).Label = sprintf('F%c (Hz)',8320); % sub 0
			app.stimData(id).Params(4).Value = 200;
			app.stimData(id).Params(5).Label = 'Formant frequencies (Hz)';
			app.stimData(id).Params(5).Value = [500 2100];
			app.stimData(id).Params(6).Label = 'Bandwidths (Hz)';
			app.stimData(id).Params(6).Value = [70 90];

			% Schroeder Phase.
			id = 11;
			app.stimData(id).Description1 = 'phase C value = 1';
			app.stimData(id).Description2 = sprintf('phase C value = %g',-1);
			app.stimData(id).UseLogAxes = false;
			app.stimData(id).NumParams = 8;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.1;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.025;
			app.stimData(id).Params(4).Label = sprintf('F%c (Hz)',8320); % sub 0
			app.stimData(id).Params(4).Value = 100;
			app.stimData(id).Params(5).Label = '# components';
			app.stimData(id).Params(5).Value = 30;
			app.stimData(id).Params(6).Label = 'C1: phase C value';
			app.stimData(id).Params(6).Value = 1;
			app.stimData(id).Params(6).Callback = @compute_C2;
			app.stimData(id).Params(7).Label = 'C2: phase C value';
			app.stimData(id).Params(7).Value = -1;
			app.stimData(id).Params(8).Label = 'Include fundamental';
			app.stimData(id).Params(8).Value = true;
			function compute_C2(~,~)
				% Compute C2 based on C1.
				params = app.stimData(11).Params;
				C1 = str2double(params(6).ValueH.Value);
				C2 = -C1;
				% Display C2.
				app.stimData(11).Params(7).ValueH.Value = sprintf('%g',C2);
				app.stimData(11).Description1H.Text = sprintf('phase C value = %g',C1);
				app.stimData(11).Description2H.Text = sprintf('phase C value = %g',C2);
			end

			% Noise-in-Notched Noise.
			id = 12;
			app.stimData(id).Description1 = 'Test (w/increment)';
			app.stimData(id).Description2 = 'Standard';
			app.stimData(id).NumParams = 4;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Duration (s)';
			app.stimData(id).Params(1).Value = 0.2;
			app.stimData(id).Params(2).Label = 'Ramp duration (s)';
			app.stimData(id).Params(2).Value = 0.01;
			app.stimData(id).Params(3).Label = 'Target noise spectrum level (dB)';
			app.stimData(id).Params(3).Value = 20;
			app.stimData(id).Params(4).Label = 'Increment (dB)';
			app.stimData(id).Params(4).Value = 5;

			% FM Tone.
			id = 13;
			app.stimData(id).Description1 = 'Reference, C1';
			app.stimData(id).Description2 = 'Signal, C2';
			app.stimData(id).NumParams = 6;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Sound level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Duration (s)';
			app.stimData(id).Params(2).Value = 0.65;
			app.stimData(id).Params(3).Label = 'Ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.01;
			app.stimData(id).Params(4).Label = 'Center frequency';
			app.stimData(id).Params(4).Value = 1000;
			app.stimData(id).Params(5).Label = sprintf('C1: [F%c %cf] ([Hz %%])',8344,8710); % sub m, \Delta
			app.stimData(id).Params(5).Value = [5 13.9];
			app.stimData(id).Params(6).Label = sprintf('C2: [F%c %cf] ([Hz %%])',8344,8710); % sub m, \Delta
			app.stimData(id).Params(6).Value = [5 20];

			% Forward Masking.
			id = 14;
			app.stimData(id).Description1 = 'Masker + Probe';
			app.stimData(id).Description2 = 'Masker only';
			app.stimData(id).NumParams = 8;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Masker level (dB SPL)';
			app.stimData(id).Params(1).Value = 65;
			app.stimData(id).Params(2).Label = 'Masker duration (s)';
			app.stimData(id).Params(2).Value = 0.2;
			app.stimData(id).Params(3).Label = 'Masker ramp duration (s)';
			app.stimData(id).Params(3).Value = 0.004;
			app.stimData(id).Params(4).Label = 'Probe duration (s)';
			app.stimData(id).Params(4).Value = 0.016;
			app.stimData(id).Params(5).Label = 'Probe level (dB SPL)';
			app.stimData(id).Params(5).Value = 40;
			app.stimData(id).Params(6).Label = 'Masker frequency (Hz)';
			app.stimData(id).Params(6).Value = 2000;
			app.stimData(id).Params(7).Label = 'Probe frequency (Hz)';
			app.stimData(id).Params(7).Value = 2000;
			app.stimData(id).Params(8).Label = 'Delay (s)';
			app.stimData(id).Params(8).Value = 0.01;

			% CMR_BW.
			id = 15;
			app.stimData(id).Description1 = 'Modulated w/tone';
			app.stimData(id).Description2 = 'Unmodulated w/tone';
			app.stimData(id).NumParams = 9;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Tone duration (s)';
			app.stimData(id).Params(1).Value = 0.3;
			app.stimData(id).Params(2).Label = 'Tone ramp duration (s)';
			app.stimData(id).Params(2).Value = 0.05;
			app.stimData(id).Params(3).Label = 'Tone Frequency (Hz)';
			app.stimData(id).Params(3).Value = 1000;
			app.stimData(id).Params(4).Label = 'Tone level (dB SPL)';
			app.stimData(id).Params(4).Value = 65;
			app.stimData(id).Params(5).Label = 'Noise bandwidth (Hz)';
			app.stimData(id).Params(5).Value = 400;
			app.stimData(id).Params(6).Label = 'Noise spectrum level (dB Pa/Hz)';
			app.stimData(id).Params(6).Value = 30;
			app.stimData(id).Params(7).Label = 'Noise duration (s)';
			app.stimData(id).Params(7).Value = 0.6;
			app.stimData(id).Params(8).Label = 'Noise ramp duration (s)';
			app.stimData(id).Params(8).Value = 0.01;
			app.stimData(id).Params(9).Label = 'Modulation bandwidth (Hz)';
			app.stimData(id).Params(9).Value = 50;

			% CMR_FB.
			id = 16;
			app.stimData(id).Description1 = 'Comodulated noise with tone';
			app.stimData(id).Description2 = 'Codeviant noise with tone';
			app.stimData(id).NumParams = 6;
			app.stimData(id).Params = init_params_struct(app.stimData,id);
			app.stimData(id).Params(1).Label = 'Duration (s)';
			app.stimData(id).Params(1).Value = 0.3;
			app.stimData(id).Params(2).Label = 'Ramp duration (s)';
			app.stimData(id).Params(2).Value = 0.01;
			app.stimData(id).Params(3).Label = 'Tone Frequency (Hz)';
			app.stimData(id).Params(3).Value = 1000;
			app.stimData(id).Params(4).Label = 'Noise spectrum level (dB Pa/Hz)';
			app.stimData(id).Params(4).Value = 40;
			app.stimData(id).Params(5).Label = 'Tone level (dB SPL)';
			app.stimData(id).Params(5).Value = 65;
			app.stimData(id).Params(6).Label = 'Flank bandwidth (Hz)';
			app.stimData(id).Params(6).Value = 100;

			% Example user-added stimulus with two parameters, initially 1.1 and 2.2. (STIM)
			% id = 17;
			% app.stimData(id).Description1 = 'Description of condition 1';
			% app.stimData(id).Description2 = 'Description of condition 2';
			% app.stimData(id).NumParams = 2;
			% app.stimData(id).Params = init_params_struct(app.stimData,id);
			% app.stimData(id).Params(1).Label = 'Label for parameter 1';
			% app.stimData(id).Params(1).Value = 1.1;
			% app.stimData(id).Params(2).Label = 'Label for parameter 2';
			% app.stimData(id).Params(2).Value = 2.2;


			% How to add two radiobuttons.
			%{
			app.stimData(id).Params(5).Style = 'radiobutton';
			app.stimData(id).Params(5).Label = 'test1';
			app.stimData(id).Params(5).Value = true;
			app.stimData(id).Params(5).Callback = {@here,5:6,5};
			app.stimData(id).Params(6).Style = 'radiobutton';
			app.stimData(id).Params(6).Label = 'test2';
			app.stimData(id).Params(6).Value = false;
			app.stimData(id).Params(6).Callback = {@here,5:6,6};
				function here(~,~,kk,k)
					h = arrayfun(@(i)app.stimData(id).Params(i).ValueH,kk);
					[h.Value] = deal(false);
					app.stimData(id).Params(k).ValueH.Value = true;
				end
			%}

			% End of definition of controls for each stimulus type.
			%------------------------------------------------------
		end % function createStimData


		function createStimParamPanels(app)
			% Create all the stimulus parameter panels and populate them with
			% the controls for each stimulus type.  Only one of these panels
			% will be visible at a time.

			% Width of all panels.
			panel_width = app.StimulusParametersPanel.Position(3);

			% Number of stimulus types.
			num_stim_types = length(app.StimulusTypeDropDown.Items);

			% Create the stimulus parameter panels and their controls.
			for spi = 1:num_stim_types
				% Create the panel for this stimulus type.
				app.stimData(spi).Panel = uipanel('Parent',app.UIFigure,...
					'Units','pixels',...
					'Position',app.StimulusParametersPanel.Position,...
					'BackgroundColor',app.StimulusParametersPanel.BackgroundColor,...
					'Title',sprintf('%s Parameters',app.stimData(spi).Type),...
					'Visible','off');

				% Create the controls and their labels.
				for spj = 1:app.stimData(spi).NumParams
					% Compute the 'y' coordinate of the uicontrols.
					vertical_spacing = 25;
					y = app.stimData(spi).Panel.Position(4) - 60 - ...
						(app.stimData(spi).Params(spj).Row - 1)*vertical_spacing;

					% Determine parameter style.
					if ~isempty(app.stimData(spi).Params(spj).Style)
						% Use the Style field if it's not empty.
						style = app.stimData(spi).Params(spj).Style;
					else
						% If the Style field is empty, infer the style from other
						% information.
						if ~isempty(app.stimData(spi).Params(spj).Choices)
							style = 'popupmenu';
						elseif isnumeric(app.stimData(spi).Params(spj).Value)
							style = 'numeric';
						elseif islogical(app.stimData(spi).Params(spj).Value)
							style = 'checkbox';
						elseif ischar(app.stimData(spi).Params(spj).Value)
							style = 'string';
						elseif isstring(app.stimData(spi).Params(spj).Value)
							style = 'string';
						end
					end

					% Create the uicontrol(s).
					switch style
						case 'string'
							% Create the label text uicontrol.
							app.stimData(spi).Params(spj).LabelH = uilabel(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[20,y,180,19],...
								'Text',app.stimData(spi).Params(spj).Label,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

							% Create the edit uicontrol.
							app.stimData(spi).Params(spj).ValueH = uieditfield(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[panel_width - 120,y,100,23],...
								'Value',app.stimData(spi).Params(spj).Value,...
								'ValueChangedFcn',app.stimData(spi).Params(spj).Callback,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

						case 'numeric'
							% Create the label text uicontrol.
							app.stimData(spi).Params(spj).LabelH = uilabel(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[20,y,180,19],...
								'Text',app.stimData(spi).Params(spj).Label,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

							% Create the edit uicontrol.
							app.stimData(spi).Params(spj).ValueH = uieditfield(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[panel_width - 120,y,100,23],...
								'Value',strjoin(arrayfun(@(x){num2str(x)},...
								app.stimData(spi).Params(spj).Value)),...
								'ValueChangedFcn',app.stimData(spi).Params(spj).Callback,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

						case 'checkbox'
							% Create the checkbox.
							app.stimData(spi).Params(spj).ValueH = uicheckbox(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[20,y,180,23],...
								'Text',app.stimData(spi).Params(spj).Label,...
								'Value',app.stimData(spi).Params(spj).Value,...
								'ValueChangedFcn',app.stimData(spi).Params(spj).Callback,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

						case 'radiobutton'
							% Create the radiobutton.
							app.stimData(spi).Params(spj).ValueH = uicontrol(...
								'Parent',app.stimData(spi).Panel,...
								'Style','radiobutton',...
								'Units','pixels',...
								'Position',[20,y,180,23],...
								'String',app.stimData(spi).Params(spj).Label,...
								'Value',app.stimData(spi).Params(spj).Value,...
								'Callback',app.stimData(spi).Params(spj).Callback,...
								'ButtonDownFcn',app.stimData(spi).Params(spj).ButtonDownFcn,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

							% Adjust radiobutton width.
							app.stimData(spi).Params(spj).ValueH.Position(3) = ...
								app.stimData(spi).Params(spj).ValueH.Extent(3) + 25;

						case 'popupmenu'
							% Create the label text uicontrol.
							app.stimData(spi).Params(spj).LabelH = uilabel(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[20,y,180,19],...
								'Text',app.stimData(spi).Params(spj).Label,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

							% Create the popupmenu.
							choices = app.stimData(spi).Params(spj).Choices;
							max_chars = max(cellfun(@length,choices)) + 2;
							choices = cellfun(@(x){sprintf('%*s',-max_chars,x)},choices);
							app.stimData(spi).Params(spj).ValueH = uidropdown(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[panel_width - 150,y-2,130,23],...
								'Items',choices,...
								'ItemsData',1:length(choices),...
								'Value',app.stimData(spi).Params(spj).Value,...
								'ValueChangedFcn',app.stimData(spi).Params(spj).Callback,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

						case 'widelabel'
							% Create the label text uicontrol.
							app.stimData(spi).Params(spj).LabelH = uilabel(...
								'Parent',app.stimData(spi).Panel,...
								'Position',[20,y-26,panel_width-40,38],...
								'Text',app.stimData(spi).Params(spj).Label,...
								'Tooltip',app.stimData(spi).Params(spj).Tooltip);

					end
					% Special case for Audio File stimulus which gets two
					% "Upload" buttons.
					if ~isempty(app.stimData(spi).Params(spj).Button)
						width = app.stimData(spi).Params(spj).Button{2};
						uibutton(app.stimData(spi).Panel,...
							'Text',app.stimData(spi).Params(spj).Button{1},...
							'Position',[panel_width-130-width,y,width,23],...
							'ButtonPushedFcn',app.stimData(spi).Params(spj).Button{3},...
							'Tooltip','Click here to upload your own audio file.');
					end
				end

				% Create the description items.
				xw = 27;
				uilabel(app.stimData(spi).Panel,...
					'Position',[20 45 xw 25],...
					'FontSize',14,...
					'FontWeight','bold',...
					'FontColor',[0 0.7 0],...
					'Text','C1:');
				uilabel(app.stimData(spi).Panel,...
					'Position',[20 20 xw 25],...
					'FontSize',14,...
					'FontWeight','bold',...
					'FontColor','b',...
					'Text','C2:');
				app.stimData(spi).Description1H = uilabel(app.stimData(spi).Panel,...
					'Position',[20+xw 45 panel_width-40-xw 25],...
					'FontSize',14,...
					'Text',app.stimData(spi).Description1);
				app.stimData(spi).Description2H = uilabel(app.stimData(spi).Panel,...
					'Position',[20+xw 20 panel_width-40-xw 25],...
					'FontSize',14,...
					'Text',app.stimData(spi).Description2);
			end
		end % function createStimParamPanels


		function createModelParamPanel(app)
			% Create the Model Parameters panel and its controls.

			% Set a default range of CFs; this can be updated on GUI.
			cfRange = [200 3000];

			% Set the vertical separation between rows of controls, and an anonymous
			% function to compute the height within the panel of a control with a specified
			% row number.  Rows are numbered from the bottom and need not be integers.
			vertical_spacing = 35;
			height = @(row)40 + (row - 1)*vertical_spacing;

			% Get handle to panel for model parameters and its width.
			modelParamPanel = app.ModelParametersPanel;
			panel_width = modelParamPanel.Position(3);

			%--------------------------------------------------------------------------

			% When adding an AN model, add it here.  ANmodelTypeOptions are the strings that
			% appear in the popup menu.
			ANmodelTypeOptions = {'Zilany, Bruce & Carney 2014',...
				'Bruce, Erfani & Zilany 2018'};

			% Create AN model popup menu label.
			AN_model_label = uilabel(modelParamPanel,...
				'Position',[15,height(9+2/3),40,19],...
				'Text','AN: ',...
				'HorizontalAlignment','left',...
				'FontWeight','bold');

			% Create AN model popup menu.
			app.AN_model_popup = uidropdown(modelParamPanel,...
				'Position',[15+AN_model_label.Position(3),height(9+2/3)-2,200,23],...
				'Items',ANmodelTypeOptions,...
				'ItemsData',1:length(ANmodelTypeOptions),...
				'Value',app.Which_AN,...
				'ValueChangedFcn',@app.select_AN_model);
			app.AN_model_popup.Position(3) = panel_width - 15 - app.AN_model_popup.Position(1);

			% Create controls for each AN model choice (if any).
			% The visiblity of these controls is set in the callback for AN_model_popup.
			app.num_fibers_label = uilabel(modelParamPanel,...
				'Position',[25,height(8+2/3),160,19],...
				'Text','Number of fibers in each CF:',...
				'HorizontalAlignment','left');

			app.num_fibers_edit = uieditfield(modelParamPanel,...
				'Position',[25+app.num_fibers_label.Position(3),height(8+2/3),40,23],...
				'Value','10',...
				'HorizontalAlignment','center');


			% Hearing loss section of Model Parameters panel.
			%--------------------------------------------------------------------------
			% OHC input text on GUI.
			hl_width = 108;
			uilabel(modelParamPanel,...
				'Position',[25,height(7+2/3),hl_width,19],...
				'Text','Audiogram (db HL):',...
				'HorizontalAlignment','left');
			uilabel(modelParamPanel,...
				'Position',[25,height(7.1),hl_width,17],...
				'Text','Frequency (Hz):',...
				'HorizontalAlignment','left');

			app.OHCEdit = gobjects(1,7);
			for i = 1:7
				app.OHCEdit(i) = uieditfield(modelParamPanel,...
					'Position',[25+hl_width+27*(i-1),height(7+2/3),26,23],...
					'Value','0',...
					'HorizontalAlignment','center');
			end

			% Frequency labels.
			% 1/8, 1/4, 1/2, 1, 2, 4, 8 kHz.
			freq_labels = {'125','250','500','1k','2k','4k','8k'};
			h = gobjects(1,7);
			for i = 1:7
				h(i) = uilabel(modelParamPanel,...
					'Position',[25+hl_width+27*(i-1),height(7.1),26,17],...
					'Text',freq_labels{i},...
					'HorizontalAlignment','center');
			end
			% Set the FontSize of the fractional labels to be a little larger.
			% 			set(h(1:3),'FontSize',h(1).FontSize+2)


			% CF Range section of Model Parameters panel.
			%--------------------------------------------------------------------------
			app.CFEditLo_label = uilabel(modelParamPanel,...
				'Position',[25,height(6),120,19],...
				'HorizontalAlignment','left',...
				'Text','CF Range (Hz):');

			app.CFEditLo = uieditfield(modelParamPanel,...
				'Position',[25 + app.CFEditLo_label.Position(3),height(6),60,23],...
				'HorizontalAlignment','center',...
				'Value',sprintf('%g',cfRange(1)));

			x = sum(app.CFEditLo.Position([1 3]));
			app.CFEditHi_label = uilabel(modelParamPanel,...
				'Position',[x,height(6),50,19],...
				'HorizontalAlignment','center',...
				'Text',' to ');

			app.CFEditHi = uieditfield(modelParamPanel,...
				'Position',[x + app.CFEditHi_label.Position(3),height(6),60,23],...
				'HorizontalAlignment','center',...
				'Value',sprintf('%g',cfRange(2)));


			% Number of CFs section of Model Parameters panel.
			%--------------------------------------------------------------------------
			numFibersEdit_label = uilabel(modelParamPanel,...
				'Position',[25,height(5),120,19],...
				'HorizontalAlignment','left',...
				'Text','Number of CFs:');

			app.numCFEdit = uieditfield(modelParamPanel,...
				'Position',[25+numFibersEdit_label.Position(3),height(5),50,23],...
				'HorizontalAlignment','center',...
				'Value',sprintf('%g',app.DefaultNumCFs));


			% AN Species section of Model Parameters panel.
			%--------------------------------------------------------------------------
			speciesTypePopup_label = uilabel(modelParamPanel,...
				'Position',[25,height(4),120,19],...
				'HorizontalAlignment','left',...
				'Text','AN Species:');

			% Default value set to 2 (Human).
			app.speciesTypePopup = uidropdown(modelParamPanel,...
				'Position',[25+speciesTypePopup_label.Position(3),height(4)-2,180,23],...
				'Items',{'Cat','Human (Shera tuning)'},...
				'ItemsData',1:2,...
				'Value',2);

			% AN spontaneous rate section of Model Parameters panel.
			%--------------------------------------------------------------------------
			spont_rate_label = uilabel(modelParamPanel,...
				'Position',[25,height(3),120,19],...
				'HorizontalAlignment','left',...
				'Text','AN Spont rate:');

			app.spontTypePopup = uidropdown(modelParamPanel,...
				'Position',[25+spont_rate_label.Position(3),height(3)-2,100,23],...
				'Items',{'Low','Med','High'},...
				'ItemsData',1:3,...
				'Value',3); % DEFAULT VALUE SET TO 3 (High spont)

			%--------------------------------------------------------------------------

			% When adding an IC model, add it here.  ICmodelTypeOptions are the strings that
			% appear in the popup menu.  When a selection is made, the Tooltip string of the
			% popup menu is set to the corresponding IC_model_credits string.
			ICmodelTypeOptions = {'SFIE Model','AN Modulation Filter Model'};
			app.IC_model_credits = {'Nelson & Carney, 2004; Carney et al, 2015',...
				'Mao et al, 2013'};
			num_IC_options = length(ICmodelTypeOptions);

			% Create IC model popup menu label.
			IC_model_label = uilabel(modelParamPanel,...
				'Position',[15,height(2),40,19],...
				'Text','IC: ',...
				'HorizontalAlignment','left',...
				'FontWeight','bold');
			% IC_model_label.Position(3) = IC_model_label.Extent(3);

			% Create IC model popup menu.
			app.IC_model_popup = uidropdown(modelParamPanel,...
				'Position',[15+IC_model_label.Position(3),height(2)-2,200,23],...
				'Items',ICmodelTypeOptions,...
				'ItemsData',1:length(ICmodelTypeOptions),...
				'Value',app.Which_IC,...
				'ValueChangedFcn',@app.select_IC_model);
			app.IC_model_popup.Position(3) = panel_width - 15 - app.IC_model_popup.Position(1);

			% Create cell arrays for the IC model options input data and their labels.
			app.IC_data_edit = cell(1,num_IC_options);
			app.IC_data_labels = cell(1,num_IC_options);

			% Define input edit boxes and labels for option 1.
			app.IC_data_labels{1} = uilabel(modelParamPanel,...
				'Position',[25,height(1),250,23],...
				'Text','BMF (Hz):  smaller of                        and CF/4',...
				'HorizontalAlignment','left',...
				'Visible','off');

			app.BMF = 100;
			app.IC_data_edit{1} = uieditfield(modelParamPanel,...
				'Position',[145,height(1),60,23],...
				'Value',num2str(app.BMF),...
				'HorizontalAlignment','center',...
				'Visible','off');

			% Define input edit boxes and labels for option 2.
			app.IC_data_labels{2} = uilabel(modelParamPanel,...
				'Position',[25,height(1),250,23],...
				'Text','BMF (Hz):  smaller of                        and CF/4',...
				'HorizontalAlignment','left',...
				'Visible','off');

			app.BMF = 100;
			app.IC_data_edit{2} = uieditfield(modelParamPanel,...
				'Position',[145,height(1),60,23],...
				'Value',num2str(app.BMF),...
				'HorizontalAlignment','center',...
				'Visible','off');

			% Set the default dropdown and label to be visible.
			app.IC_data_labels{app.Which_IC}.Visible = 'on';
			app.IC_data_edit{app.Which_IC}.Visible = 'on';

		end % function createModelParamPanel


		function createNarrowAxes(app)
			% Create output panel and contents for "narrow" plots.

			% Get the handle to the panel.
			output_panel_n = app.Panel;

			% Positioning parameters for the various axes.
			% Horizontal.
			left = 60;
			right = 20;
			xgaps = [70 120];
			nx = 3;
			width = (output_panel_n.Position(3) - left - right - sum(xgaps))/nx;
			x = left + (0:nx-1)*width + cumsum([0,xgaps]);
			% Vertical.
			bottom = 60;
			top = 40;
			ygap = 70;
			ny = 3;
			height = (output_panel_n.Position(4) - top - bottom - (ny-1)*ygap)/ny;
			y = bottom + (ny-1:-1:0)*(height + ygap);

			% Narrow waveform plot.
			app.wav_axes_n = uiaxes(output_panel_n,'Box','on');
			app.wav_axes_n.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.wav_axes_n)
			app.stim_wav_line = line(app.wav_axes_n,'XData',NaN,'YData',NaN,'HitTest','off');
			app.selected_line = [];
			app.analysis_window_lines = gobjects(1,2);
			app.analysis_window_lines(1) = xline(app.wav_axes_n,app.aw_t(1),...
				'Color',app.selection_line_color,...
				'LineWidth',1,...
				'ButtonDownFcn',{@app.sel_line_down,1},...
				'Tag','delete when exported');
			app.analysis_window_lines(2) = xline(app.wav_axes_n,app.aw_t(2),...
				'Color',app.selection_line_color,...
				'LineWidth',1,...
				'ButtonDownFcn',{@app.sel_line_down,2},...
				'Tag','delete when exported');
			ylabel(app.wav_axes_n,'Amplitude (Pa)')
			xlabel(app.wav_axes_n,'Time (s)')
			app.wav_axes_n.ButtonDownFcn = @app.next_wave;
			app.wav_axes_n.InnerPosition = [x(1),y(1),width,height];
			app.make_exportable(app.wav_axes_n)

			% Narrow spectrogram plot.
			app.specgram_axes_n = uiaxes(output_panel_n,'Box','on');
			app.specgram_axes_n.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.specgram_axes_n)
			app.specgram_surf = surface(app.specgram_axes_n,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			ylabel(app.specgram_axes_n,'Frequency (Hz)')
			app.specgram_axes_n.ButtonDownFcn = @app.next_specgram;
			app.specgram_axes_n.InnerPosition = [x(1),y(2),width,height];
			app.make_exportable(app.specgram_axes_n,'surface')

			% Narrow spectrum plot.
			app.spec_axes_n = uiaxes(output_panel_n,'Box','on');
			app.spec_axes_n.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.spec_axes_n)
			app.spec_line = plot(app.spec_axes_n,NaN,NaN,'HitTest','off');
			ylabel(app.spec_axes_n,'Magnitude (dB SPL)')
			xlabel(app.spec_axes_n,'Frequency (Hz)')
			grid(app.spec_axes_n,'on')
			app.spec_axes_n.ButtonDownFcn = @app.next_spec;
			app.spec_axes_n.InnerPosition = [x(1),y(3),width,height];
			app.make_exportable(app.spec_axes_n)

			% Narrow AN response plot.
			app.AN_axes_n = uiaxes(output_panel_n,'Box','on');
			app.AN_axes_n.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.AN_axes_n)
			app.AN_surf_n = surface(app.AN_axes_n,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			xlabel(app.AN_axes_n,'Time (s)')
			ylabel(app.AN_axes_n,'AN CF (Hz)')
			app.AN_axes_n.TickDir = 'out';
			app.AN_axes_n.ButtonDownFcn = @app.next_AN_model;
			app.AN_model_cb = colorbar(app.AN_axes_n,'Visible','on');
			title(app.AN_model_cb,'sp/s');
			app.AN_axes_n.InnerPosition = [x(2),y(1)-(height+ygap)/2,width,height];
			app.make_exportable(app.AN_axes_n,'surface')

			% Narrow IC response plot.
			app.IC_axes_n = uiaxes(output_panel_n,'Box','on');
			app.IC_axes_n.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.IC_axes_n)
			app.IC_surf_n = surface(app.IC_axes_n,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			ylabel(app.IC_axes_n,'IC CF (Hz)')
			xlabel(app.IC_axes_n,'Time (s)')
			app.IC_axes_n.TickDir = 'out';
			app.IC_axes_n.ButtonDownFcn = @app.next_IC_model;
			app.IC_model_cb = colorbar(app.IC_axes_n,'Visible','on');
			title(app.IC_model_cb,'sp/s');
			app.IC_axes_n.InnerPosition = [x(2),y(2)-(height+ygap)/2,width,height];
			app.make_exportable(app.IC_axes_n,'surface')

			% InnerPosition apparently includes colorbar so adjust widths.
			app.fix_axes_width(app.AN_axes_n,width)
			app.fix_axes_width(app.IC_axes_n,width)

			% Narrow AN average response plot.
			app.AN_avg_axes = uiaxes(output_panel_n,'Box','on');
			app.AN_avg_axes.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.AN_avg_axes)
			app.AN_avg_lines = plot(app.AN_avg_axes,NaN,NaN,NaN,NaN,'HitTest','off');
			for jj = 1:2
				app.AN_avg_lines(jj).Color = app.colors{jj};
				app.AN_avg_lines(jj).LineWidth = 2;
			end
			ylabel(app.AN_avg_axes,'Ave. Rate (sp/s)')
			xlabel(app.AN_avg_axes,'AN CF (Hz)')
			title(app.AN_avg_axes,'AN Ave. Rate')
			grid(app.AN_avg_axes,'on')
			app.AN_avg_axes.XTick = [100 200 500 1000 2000 5000 10000];
			% app.AN_avg_axes.ButtonDownFcn = @app.next_AN_avg;
			app.AN_avg_axes.InnerPosition = [x(3),y(1),width,height];
			app.make_exportable(app.AN_avg_axes)


			% Narrow CN average response plot.
			app.CN_avg_axes = uiaxes(output_panel_n,'Box','on');
			app.CN_avg_axes.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.CN_avg_axes)
			app.CN_avg_lines = plot(app.CN_avg_axes,NaN,NaN,NaN,NaN,'HitTest','off');
			for jj = 1:2
				app.CN_avg_lines(jj).Color = app.colors{jj};
				app.CN_avg_lines(jj).LineWidth = 2;
			end
			ylabel(app.CN_avg_axes,'Ave. Rate (sp/s)')
			xlabel(app.CN_avg_axes,'CN CF (Hz)')
			title(app.CN_avg_axes,'CN Ave. Rate')
			grid(app.CN_avg_axes,'on')
			app.CN_avg_axes.XTick = [100 200 500 1000 2000 5000 10000];
			% app.CN_avg_axes.ButtonDownFcn = @app.next_CN_avg;
			app.CN_avg_axes.InnerPosition = [x(3),y(2),width,height];
			app.make_exportable(app.CN_avg_axes)

			% Narrow IC average response plot.
			app.IC_avg_axes = uiaxes(output_panel_n,'Box','on');
			app.IC_avg_axes.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.IC_avg_axes)
			app.IC_avg_lines = plot(app.IC_avg_axes,NaN,NaN,NaN,NaN,'HitTest','off');
			for jj = 1:2
				app.IC_avg_lines(jj).Color = app.colors{jj};
				app.IC_avg_lines(jj).LineWidth = 2;
			end
			ylabel(app.IC_avg_axes,'Ave. Rate (sp/s)')
			xlabel(app.IC_avg_axes,'IC CF (Hz)')
			grid(app.IC_avg_axes,'on')
			app.IC_avg_axes.XTick = [100 200 500 1000 2000 5000 10000];
			app.IC_avg_axes.InnerPosition = [x(3),y(3),width,height];
			app.make_exportable(app.IC_avg_axes)

			% BE/BS radio buttons to switch IC average response plot.
			drawnow % necessary for Position property in next line to be valid
			ref_pos = app.IC_avg_axes.Position(1:2);
			app.be_bs_radio_group = uibuttongroup(output_panel_n,...
				'Position',[ref_pos(1)-40,ref_pos(2),40,60],...
				'BorderType','none',...
				'SelectionChangedFcn',@app.handle_be_bs_radio,...
				'Visible','on');
			app.be_bs_radio = gobjects(1,2);
			app.be_bs_radio(1) = uiradiobutton(app.be_bs_radio_group,...
				'Position',[2,30,40,24],...
				'Text','BE',...
				'Value',app.be_bs_selection == 1);
			app.be_bs_radio(2) = uiradiobutton(app.be_bs_radio_group,...
				'Position',[2,5,40,24],...
				'Text','BS',...
				'Value',app.be_bs_selection == 2);

		end % function createNarrowAxes


		function createWideAxes(app)
			% Create output panel and contents for "wide" plots.

			% Get handle to panel.
			output_panel_w = app.WidePlotsPanel;

			% Set positioning parameters for axes.
			left = 80;
			right = 40;
			bottom = 70;
			top = 50;
			gap = 50;
			n = 5;
			width = output_panel_w.Position(3) - left - right;
			height = (output_panel_w.Position(4) - top - bottom - (n-1)*gap)/n;
			y = bottom + (height + gap)*(n-1:-1:0);

			% Wide stimulus waveform plot.
			app.wav_axes_w = uiaxes(output_panel_w,'Box','on');
			app.wav_axes_w.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.wav_axes_w)
			app.stim_wav_line_w = plot(app.wav_axes_w,NaN,NaN,'HitTest','off');
			ylabel(app.wav_axes_w,'Amplitude (Pa)')
			app.wav_axes_w.ButtonDownFcn = @app.next_wide_plots;
			colorbar(app.wav_axes_w,'Visible','off');
			app.wav_axes_w.InnerPosition = [left,y(1),width,height];
			app.make_exportable(app.wav_axes_w)

			% Wide spectrogram plot.
			app.specgram_axes_w = uiaxes(output_panel_w,'Box','on');
			app.specgram_axes_w.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.specgram_axes_w)
			app.specgram_surf_w = surface(app.specgram_axes_w,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			ylabel(app.specgram_axes_w,'Frequency (Hz)')
			app.specgram_axes_w.ButtonDownFcn = @app.next_wide_plots;
			colorbar(app.specgram_axes_w,'Visible','off');
			app.specgram_axes_w.InnerPosition = [left,y(2),width,height];
			app.make_exportable(app.specgram_axes_w,'surface')

			% Wide VIHC plot.
			app.VIHC_axes_w = uiaxes(output_panel_w,'Box','on');
			app.VIHC_axes_w.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.VIHC_axes_w)
			app.VIHC_surf_w = surface(app.VIHC_axes_w,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			ylabel(app.VIHC_axes_w,'IHC CF (Hz)')
			% 			app.VIHC_axes_w.TickDir = 'out';
			app.VIHC_axes_w.ButtonDownFcn = @app.next_wide_plots;
			app.VIHC_axes_w.Visible = 'on';
			caxis(app.VIHC_axes_w,[-5 15]); % mV, based on 75 dB SPL speech
			app.VIHC_cb = colorbar(app.VIHC_axes_w);
			app.VIHC_cb.Color = [0.75 0.75 0.75];
			app.VIHC_cb.Ruler.Color = [0.15 0.15 0.15];
			title(app.VIHC_cb,'mV');
			app.VIHC_axes_w.InnerPosition = [left,y(3),width,height];
			app.make_exportable(app.VIHC_axes_w,'surface')

			% Wide AN model response plot.
			app.AN_axes_w = uiaxes(output_panel_w,'Box','on');
			app.AN_axes_w.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.AN_axes_w)
			app.AN_surf_w = surface(app.AN_axes_w,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			ylabel(app.AN_axes_w,'AN CF (Hz)')
			% 			app.AN_axes_w.TickDir = 'out';
			app.AN_axes_w.ButtonDownFcn = @app.next_wide_plots;
			app.AN_model_cb_w = colorbar(app.AN_axes_w);
			app.AN_model_cb_w.Color = [0.75 0.75 0.75];
			app.AN_model_cb_w.Ruler.Color = [0.15 0.15 0.15];
			title(app.AN_model_cb_w,'sp/s');
			app.AN_axes_w.InnerPosition = [left,y(4),width,height];
			app.make_exportable(app.AN_axes_w,'surface')

			% Wide IC model response plot.
			app.IC_axes_w = uiaxes(output_panel_w,'Box','on');
			app.IC_axes_w.Toolbar.Visible = app.UIAxesDefaultVisibility;
			app.setInteractivity(app.IC_axes_w)
			app.IC_surf_w = surface(app.IC_axes_w,0,0,0,...
				'FaceColor','interp',...
				'EdgeColor','none',...
				'HitTest','off');
			xlabel(app.IC_axes_w,'Time (s)')
			ylabel(app.IC_axes_w,'IC CF (Hz)')
			% 			app.IC_axes_w.TickDir = 'out';
			app.IC_axes_w.ButtonDownFcn = @app.next_wide_plots;
			app.IC_model_cb_w = colorbar(app.IC_axes_w);
			app.IC_model_cb_w.Color = [0.75 0.75 0.75];
			app.IC_model_cb_w.Ruler.Color = [0.15 0.15 0.15];
			title(app.IC_model_cb_w,'sp/s');
			app.IC_axes_w.InnerPosition = [left,y(5),width,height];
			app.make_exportable(app.IC_axes_w,'surface')

			% InnerPosition apparently includes colorbar so adjust widths.
			% 			app.fix_axes_width(app.VIHC_axes_w,width)
			% 			app.fix_axes_width(app.AN_axes_w,width)
			% 			app.fix_axes_width(app.IC_axes_w,width)

		end % function createWideAxes

		function createSoundPlayers(app)
			% Create HTML-based sound player controls for web app version and
			% non-HTML-based controls otherwise.
			if app.is_webapp
				% Create HTMLC1
				app.HTMLC1 = uihtml(app.UIFigure);
				app.HTMLC1.Visible = 'off';
				app.HTMLC1.Tooltip = {''};
				app.HTMLC1.Position = [348 1540 269 72];

				% Create HTMLC2
				app.HTMLC2 = uihtml(app.UIFigure);
				app.HTMLC2.Visible = 'off';
				app.HTMLC2.Tooltip = {''};
				app.HTMLC2.Position = [689 1540 269 72];

			else
				app.media = cell(1,2);
				createMediaControls(1)
				createMediaControls(2)

				% Change the text from "Download" to "Export" if app is running
				% locally, either in MATLAB or stand-alone.
				app.PlayDownloadSoundsLabel.Text = 'Play/Export Sounds';
				app.DownloadfromcontrolsLabel.Text = strrep( ...
					app.DownloadfromcontrolsLabel.Text,'Download','Export');
				app.PlayDownloadSamplerateEditFieldLabel.Text = strrep( ...
					app.PlayDownloadSamplerateEditFieldLabel.Text,...
					'Download','Export');
			end

			function createMediaControls(idx)
				width = 35;
				gap = 3;
				dx = width + gap;
				panel_x = [348 689];
				app.media{idx}.panel = uipanel(app.UIFigure,...
					'Position',[panel_x(idx) 1540 269 72],...
					'Title','',...
					'BorderType','none',...
					'Visible','off');
				parent = app.media{idx}.panel;
				app.media{idx}.play_pause_button = uibutton(parent,'push',...
					'Position',[2 15 width 38],...
					'Text','',...
					'Icon','play_icon.png',...
					'ButtonPushedFcn',{@play_pause_sound,idx});
				app.media{idx}.stop_button = uibutton(parent,'push',...
					'Position',[2+dx 15 width 38],...
					'Text','',...
					'Icon','stop_icon.png',...
					'ButtonPushedFcn',{@stop_sound,idx});
				app.media{idx}.audio_time = uilabel(parent,...
					'Position',[2+2*dx 15 70 38],...
					'Text','0:00/0:00',...
					'HorizontalAlignment','center',...
					'FontSize',14);
				app.media{idx}.export_button = uibutton(parent,'push',...
					'Position',[2+2*dx+70+gap 17 100 34],...
					'Text','Export...',...
					'FontSize',14,...
					'Icon','export_icon.png',...
					'ButtonPushedFcn',{@export_sound,idx});
			end

			function play_pause_sound(~,~,idx)
				if strcmp(app.players{idx}.Running,'off') && app.players{idx}.CurrentSample == 1
					% Is stopped.
					play(app.players{idx})
					app.media{idx}.play_pause_button.Icon = 'pause_icon.png';
				elseif strcmp(app.players{idx}.Running,'off') && app.players{idx}.CurrentSample > 1
					% Is paused.
					resume(app.players{idx})
					app.media{idx}.play_pause_button.Icon = 'pause_icon.png';
				else
					% Is playing.
					pause(app.players{idx})
					app.media{idx}.play_pause_button.Icon = 'play_icon.png';
				end
			end

			function stop_sound(~,~,idx)
				stop(app.players{idx})
			end

			function export_sound(~,~,idx)
				if ispc || ismac
					file_types = '*.wav;*.ogg;*.flac;*.m4a;*.mp4';
					descriptions = 'Audio file (*.wav, *.ogg, *.flac, *.m4a, *.mp4)';
				else
					file_types = '*.wav;*.ogg;*.flac';
					descriptions = 'Audio file (*.wav, *.ogg, *.flac)';
				end
				filters = {file_types,descriptions;...
					'*.mat','MAT-file'};
				app.bring_mac_matlab_to_front()
				
				if ~app.is_webapp
					if isunix
						def_dir = fullfile('~','Downloads',filesep);
					else
						def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
					end
				else
					def_dir = '';
				end
				[f,p,id] = uiputfile(filters,'Save audio file as:',def_dir);
				
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end
				filename = fullfile(p,f);
				wav = app.export_waveforms{idx}{1};
				fs = app.export_waveforms{idx}{2};
				switch id
					case 1
						audiowrite(filename,wav,fs)
					case 2
						s = struct('waveform',wav','fs',fs);
						save(filename,'-struct','s')
				end
			end

		end


		function UR_EAR_model_plots(app,ag_fs,ag_dbloss,fiber_num,CF_num)
			% Compute and display AN and IC population responses as a function of
			% Characteristic Frequency (CF) and Best Modulation Frequency (BMF).
			% Inputs: Condition.stimulus (pressure input, in pascals)
			%         cohc = Outer hair cell function (0-1 where 1 is normal)
			%         cihc = inner hair cell function

			% Initialize counters for toggling plots between conditions.
			app.clicks_wave_n = 0;        % Stimulus waveform plot
			app.clicks_specgram_n = 0;    % Spectrogram plot
			app.clicks_spec_n = 0;        % Spectrum CF range
			app.clicks_an_timefreq = 0;   % AN time_freq plot
			app.clicks_ic_timefreq = 0;   % IC time_freq plot
			app.clicks_ic_avg_rate = 0;   % For toggling between IC BP and IC BS
			app.clicks_wave_w = 0;
			app.clicks_specgram_w = 0;
			app.clicks_vihc_timefreq = 0; % AN time_freq plot
			app.clicks_an_timefreq_w = 0;
			app.clicks_ic_timefreq_w = 0;
			app.clicks_wide = 0;


			% Start parallel pool.
			app.ParPool = gcp('nocreate');
			if app.UseParallel && isempty(app.ParPool)
				app.Progressbar.Title = 'Please Wait';
				app.Progressbar.Message = 'Starting parallel pool…';
				app.Progressbar.Indeterminate = 'on';
				app.Progressbar.Cancelable = 'off';
				% Determine number of processors.  Use half of them in web app...
				cluster_info = parcluster('local');
				num_processors = cluster_info.NumWorkers;
				if app.is_webapp
					num_workers = floor(num_processors/2);
				else
					num_workers = num_processors;
				end
				app.Progressbar.Message = sprintf('Starting parallel pool (%d workers)…',num_workers);
				app.ParPool = parpool('local',num_workers);
				figure(app.UIFigure)
			end

			% Model Selection and Parameters
			% ------------------------------

			fiberType = app.spontTypePopup.Value; % AN fiber type. (1 = low SR, 2 = medium SR, 3 = high SR)
			% Set implnt = 0 => approximate model, 1 => exact power law
			% implementation (See Zilany et al., 2009)
			implnt = 0;
			% Set noiseType to 0 for fixed fGn or 1 for variable fGn - this is the
			% 'noise' associated with spontaneous activity of AN fibers - see Zilany
			% et al., 2009. 0 lets you "freeze" it.
			noiseType = 1;
			species = app.speciesTypePopup.Value; % 1=cat; 2=human AN model parameters (with Shera tuning sharpness)
			app.CFs = logspace(log10(app.CF_range(1)),log10(app.CF_range(2)),CF_num); % set range and resolution of CFs here
			app.CFs([1 end]) = app.CF_range; % force end points to be exact.
			if app.Which_IC == 1
				app.BMF = str2double(app.IC_data_edit{1}.Value);
			elseif app.Which_IC == 2
				app.BMF = str2double(app.IC_data_edit{2}.Value);
			end

			dbloss = interp1(ag_fs,ag_dbloss,app.CFs,'linear','extrap');
			[cohc_vals,cihc_vals] = fitaudiogram2(app.CFs,dbloss,species);
			if cohc_vals(1) == 0
				% For a very low CF, a 0 may be returned by Bruce et al. fit
				% audiogram, but this is a bad default.  Set it to 1 here.
				cohc_vals(1) = 1;
			end
			if cihc_vals(1) == 0
				% For a very low CF, a 0 may be returned, but this is a bad default.
				% Set it to 1 here.
				cihc_vals(1) = 1;
			end

			% Check for NaN in stimulus.  This prevents NaN from being passed into
			% mex files and causing MATLAB to close.
			for NaN_check_i = app.nconditions
				if any(isnan(app.Condition(NaN_check_i).stimulus))
					uialert(app.UIFigure,['One or more fields of the UR_EAR ',...
						'input were left blank or completed incorrectly.'],'')
					return
				end
			end

			% Set up and RUN the simulation.
			% Loop through conditions.
			nrep = 1;
			% Set max. number of iterations for waitbar.
			num_CFs = length(app.CFs);
			num_iters = app.nconditions*num_CFs;
			app.DurMax = 0;
			iter = 0;
			app.Progressbar.Message = sprintf('Computing models…');
			app.Progressbar.Indeterminate = 'off';
			app.Progressbar.Cancelable = 'on';
			app.Progressbar.Value = 0;
			pfun = repmat(parallel.FevalFuture,num_iters,1);
			out = cell(num_iters,6);
			DQ = parallel.pool.DataQueue;
			afterEach(DQ, @updateProgress);
			for ci = 1:app.nconditions % One or two stimulus conditions
				% Initialize fields.
				app.Condition(ci).VIHC_population = [];
				app.Condition(ci).an_sout_population = [];
				app.Condition(ci).an_sout_population_plot = [];
				app.Condition(ci).BE_sout_population = [];
				app.Condition(ci).BS_sout_population = [];
				app.Condition(ci).cn_sout_contra = [];

				% Get length of stimulus.
				app.Condition(ci).N = length(app.Condition(ci).stimulus);

				% Compute spectrum (FFT) of stimulus.
				app.Condition(ci).dur = app.Condition(ci).N/app.Fs;
				app.Condition(ci).spec = db(abs(sqrt(2)*fft(app.Condition(ci).stimulus)/app.Condition(ci).N/app.Pref));
				app.Condition(ci).fspec = (0:app.Condition(ci).N-1)/app.Condition(ci).dur;

				% Compute spectrogram of stimulus.
				% 50% overlap (using even numbers to avoid "beating" with F0 for speech)
				[app.Condition(ci).specgram,app.Condition(ci).fsg,app.Condition(ci).tsg] = spectrogram(...
					app.Condition(ci).stimulus,hanning(1000),[],2000,app.Fs);

				app.Condition(ci).dur2 = min(app.Condition(ci).dur + 0.04,...
					1.2*app.Condition(ci).dur);
				dur = app.Condition(ci).dur; % duration of waveform in sec
				dur2 = app.Condition(ci).dur2;
				T = 1/app.Fs;
				app.DurMax = max(app.DurMax,app.Condition(ci).dur2);

				% Loop through CFs (within app.nconditions loop)
				for n = 1:num_CFs
					m = (ci - 1)*num_CFs + n;

					% Get one element of each array.
					CF = app.CFs(n); % CF in Hz;
					cohc = cohc_vals(n);
					cihc = cihc_vals(n);

					% Adjust BMF to be no larger than CF/4;
					thisBMF = min(app.BMF,CF/4);

					if app.UseParallel
						pfun(m) = parfeval(@model,6,DQ,app.Which_AN,app.Which_IC,...
							app.Condition(ci).stimulus,app.Fs,app.CF_range,...
							app.RsFs,thisBMF,CF,nrep,T,dur,dur2,cohc,cihc,...
							ag_fs,ag_dbloss,CF_num,species,fiberType,noiseType,...
							implnt,fiber_num,n);
					else
						[out{m,:}] = model(DQ,app.Which_AN,app.Which_IC,...
							app.Condition(ci).stimulus,app.Fs,app.CF_range,...
							app.RsFs,thisBMF,CF,nrep,T,dur,dur2,cohc,cihc,...
							ag_fs,ag_dbloss,CF_num,species,fiberType,noiseType,...
							implnt,fiber_num,n);
					end

				end % end of CF loop
			end  % end of app.nconditions loop
			if app.UseParallel
				wait(pfun)
			end

			% Check for abort.
			if app.Abort
				close(app.Progressbar)
				return
			end

			if app.UseParallel
				[app.Condition(1).VIHC_population,...
					app.Condition(1).an_sout_population,...
					app.Condition(1).an_sout_population_plot,...
					app.Condition(1).BE_sout_population,...
					app.Condition(1).BS_sout_population,...
					app.Condition(1).cn_sout_contra] = fetchOutputs(pfun(1:num_CFs));
				if app.nconditions == 2
					[app.Condition(2).VIHC_population,...
						app.Condition(2).an_sout_population,...
						app.Condition(2).an_sout_population_plot,...
						app.Condition(2).BE_sout_population,...
						app.Condition(2).BS_sout_population,...
						app.Condition(2).cn_sout_contra] = fetchOutputs(pfun(num_CFs+1:end));
				end
			else
				app.Condition(1).VIHC_population = vertcat(out{1:num_CFs,1});
				app.Condition(1).an_sout_population = vertcat(out{1:num_CFs,2});
				app.Condition(1).an_sout_population_plot = vertcat(out{1:num_CFs,3});
				app.Condition(1).BE_sout_population = vertcat(out{1:num_CFs,4});
				app.Condition(1).BS_sout_population = vertcat(out{1:num_CFs,5});
				app.Condition(1).cn_sout_contra = vertcat(out{1:num_CFs,6});
				if app.nconditions == 2
					app.Condition(2).VIHC_population = vertcat(out{num_CFs+1:end,1});
					app.Condition(2).an_sout_population = vertcat(out{num_CFs+1:end,2});
					app.Condition(2).an_sout_population_plot = vertcat(out{num_CFs+1:end,3});
					app.Condition(2).BE_sout_population = vertcat(out{num_CFs+1:end,4});
					app.Condition(2).BS_sout_population = vertcat(out{num_CFs+1:end,5});
					app.Condition(2).cn_sout_contra = vertcat(out{num_CFs+1:end,6});
				end
			end


			app.Progressbar.Indeterminate = 'on';

			if app.StandardCheckBox.Value

				% Update progressbar message.
				app.Progressbar.Message = sprintf('Rendering standard plots…');

				if ~strcmp(app.AutoscrollButtonGroup.SelectedObject.Text,'Off')
					scroll(app.UIFigure,1,app.Panel.Position(2))
				end

				% Set axes limits on the AN Model and IC model plots.
				app.AN_axes_n.XLim = app.aw_t;
				app.IC_axes_n.XLim = app.aw_t;

				% Update all plots by running the ButtonDownFcn of each.
				app.be_bs_radio_group.SelectedObject = app.be_bs_radio(1);
				app.handle_be_bs_radio([],[],1) % set BE/BS to show BE plot.

				% Set up an array of function handles and run them in turn.
				fcns = {@app.next_wave, @app.next_specgram,...
					@app.next_spec, @app.next_AN_model,...
					@app.next_IC_model, @app.next_AN_avg,...
					@app.next_CN_avg, @app.next_IC_avg};
				for fi = 1:length(fcns)
					% Check for abort.
					if app.Progressbar.CancelRequested
						close(app.Progressbar)
						return
					end
					fcns{fi}()
					drawnow
				end

			else
				% Update progressbar message.
				app.Progressbar.Message = sprintf('Clearing standard plots…');
				app.clear_standard_plots()
			end

			if app.WideCheckBox.Value
				app.Progressbar.Message = sprintf('Rendering wide plots…');
				if ~strcmp(app.AutoscrollButtonGroup.SelectedObject.Text,'Off')
					scroll(app.UIFigure,1,app.WidePlotsPanel.Position(2))
				end
				app.next_wide_plots()
			else
				app.Progressbar.Message = sprintf('Clearing wide plots…');
				app.clear_wide_plots()
			end

			% Close progressbar.
			close(app.Progressbar)

			% Scroll to desired set of plots.
			switch app.AutoscrollButtonGroup.SelectedObject.Text
				case 'to Standard Plots'
					scroll(app.UIFigure,1,app.Panel.Position(2))
				case 'to Wide Plots'
					scroll(app.UIFigure,1,app.WidePlotsPanel.Position(2))
			end

			function updateProgress(~)
				iter = iter + 1;
				app.Progressbar.Value = iter/num_iters;
				app.Progressbar.Message = 'Computing models…';
				if app.Progressbar.CancelRequested
					app.Abort = true;
					cancel(pfun)
				end
			end

		end % function UR_EAR_model_plots


		function handle_be_bs_radio(app,~,evt,k)
			% Handle BE/BS radio buttons on IC model graph.
			if nargin < 4
				k = find(evt.NewValue == app.be_bs_radio);
			end
			app.be_bs_selection = k;
			app.next_IC_avg()
		end


		function next_wave(app,~,~)
			% User clicked on stimulus waveform plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			set(app.analysis_window_lines,'Visible','off')
			app.clicks_wave_n = rem(app.clicks_wave_n,app.nconditions) + 1;
			time = (0:app.Condition(app.clicks_wave_n).N-1)/app.Fs;
			app.stim_wav_line.XData = time;
			app.stim_wav_line.YData = app.Condition(app.clicks_wave_n).stimulus;
			app.stim_wav_line.Color = app.colors{app.clicks_wave_n};
			app.wav_axes_n.XLim = [0,app.Condition(app.clicks_wave_n).dur];
			app.wav_axes_n.YLimMode = 'auto';
			app.wav_axes_n.Visible = 'on';
			fmt = 'Waveform: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.wav_axes_n,sprintf(fmt,app.stim_wav_line.Color,app.clicks_wave_n))
			set(app.analysis_window_lines,'Visible','on')
		end


		function next_specgram(app,~,~)
			% User clicked on spectrogram plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			app.clicks_specgram_n = rem(app.clicks_specgram_n,app.nconditions) + 1;
			app.specgram_surf.XData = app.Condition(app.clicks_specgram_n).tsg;
			app.specgram_surf.YData = app.Condition(app.clicks_specgram_n).fsg;
			app.specgram_surf.CData = abs(app.Condition(app.clicks_specgram_n).specgram);
			app.specgram_surf.ZData = zeros(size(app.specgram_surf.CData));
			app.specgram_axes_n.XLim = [0,app.Condition(app.clicks_specgram_n).dur];
			app.specgram_axes_n.YLim = [0.9 1.1].*app.CF_range;
			app.specgram_axes_n.Visible = 'on';
			fmt = 'Spectrogram: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			str = sprintf(fmt,app.colors{app.clicks_specgram_n},app.clicks_specgram_n);
			title(app.specgram_axes_n,str)
		end


		function next_spec(app,~,~)
			% User clicked on spectrum plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			app.clicks_spec_n = rem(app.clicks_spec_n,app.nconditions) + 1;
			spec_max = max(arrayfun(@(x)max(x.spec(:)),app.Condition(1:app.nconditions)));
			app.spec_line.XData = app.Condition(app.clicks_spec_n).fspec; % fres*(0:m-1);
			app.spec_line.YData = app.Condition(app.clicks_spec_n).spec;

			app.spec_line.Color = app.colors{app.clicks_spec_n};
			app.spec_axes_n.XLim = app.CF_range;
			app.spec_axes_n.YLim = [-47 3] + spec_max; % 50 dB span
			app.spec_axes_n.XTick = [100 200 500 1000 2000 5000 10000];
			app.spec_axes_n.Visible = 'on';
			fmt = 'Spectrum CF range: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.spec_axes_n,sprintf(fmt,app.spec_line.Color,app.clicks_spec_n))
			% Set axes scaling to linear or log.
			if ~app.stimData(app.stimIdx).UseLogAxes
				app.spec_axes_n.XScale = 'linear';
			elseif app.stimData(app.stimIdx).UseLogAxes
				app.spec_axes_n.XScale = 'log';
			end
		end


		function next_AN_model(app,~,~)
			% User clicked on AN model plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			app.clicks_an_timefreq = rem(app.clicks_an_timefreq,app.nconditions) + 1;

			if app.Which_AN == 1
				data = app.Condition(app.clicks_an_timefreq).an_sout_population;
				app.AN_surf_n.XData = (0:length(data)-1)/app.RsFs;
			elseif app.Which_AN == 2
				% an_sout_population data has a sample rate = Fs/16, and is
				% delayed by 10 ms.
				Fs2 = app.Fs/16;
				data = app.Condition(app.clicks_an_timefreq).an_sout_population_plot;
				app.AN_surf_n.XData = (0:length(data)-1)/Fs2 - 10e-3; % shift by 10 ms
			end
			app.AN_surf_n.YData = app.CFs;
			app.AN_surf_n.ZData = zeros(size(data));
			app.AN_surf_n.CData = data;
			app.AN_axes_n.XLim = app.aw_t;
			app.AN_axes_n.YLim = app.CF_range;
			fmt = 'AN Model: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.AN_axes_n,sprintf(fmt,app.colors{app.clicks_an_timefreq},app.clicks_an_timefreq))
			app.AN_axes_n.Visible = 'on';
			app.AN_model_cb.Visible = 'on';
		end


		function next_IC_model(app,~,~)
			% User clicked on IC model plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			app.clicks_ic_timefreq = rem(app.clicks_ic_timefreq,app.nconditions) + 1;

			data = app.Condition(app.clicks_ic_timefreq).BE_sout_population;
			N = length(data);
			time = (0:N-1)/app.RsFs;
			% Update the surface and axes properties.
			app.IC_surf_n.XData = time;
			app.IC_surf_n.YData = app.CFs;
			app.IC_surf_n.ZData = zeros(size(data));
			app.IC_surf_n.CData = data;
			app.IC_axes_n.XLim = app.aw_t;
			app.IC_axes_n.YLim = app.CF_range;
			% Set the title.
			fmt = 'IC Model (BE Cell): {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.IC_axes_n,sprintf(fmt,app.colors{app.clicks_ic_timefreq},app.clicks_ic_timefreq))
			% Make the axes and its colorbar visible.
			app.IC_axes_n.Visible = 'on';
			app.IC_model_cb.Visible = 'on';
		end


		function next_AN_avg(app,~,~)
			% User clicked on AN average plot.  Replot both conditions.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			set(app.AN_avg_lines,'Visible','off')
			idx = round(min(max(app.aw_t,0),app.Condition(1).dur)*app.RsFs) + [1 0];
			for ci = 1:app.nconditions
				app.AN_avg_lines(ci).XData = app.CFs;
				app.AN_avg_lines(ci).YData = mean(app.Condition(ci).an_sout_population(:,idx(1):idx(2)),2);
				app.AN_avg_lines(ci).Visible = 'on';
			end
			app.AN_avg_axes.XLim = app.CF_range;
			app.AN_avg_axes.YLimMode = 'auto';
			app.AN_avg_axes.YLim(1) = 0;
			if app.stimData(app.stimIdx).UseLogAxes
				app.AN_avg_axes.XScale = 'log';
			else
				app.AN_avg_axes.XScale = 'linear';
			end
			app.AN_avg_axes.Visible = 'on';
			app.AN_avg_axes.UserData = sprintf('AN Ave. Rate (%.3g to %.3g s)',app.aw_t);
		end


		function next_CN_avg(app,~,~)
			% User clicked on CN average plot.  Replot both conditions.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			set(app.CN_avg_lines,'Visible','off')
			if ~isfield(app.Condition(1),'cn_sout_contra') || isempty(app.Condition(1).cn_sout_contra)
				app.CN_avg_axes.Visible = 'off';
				return
			end
			idx = round(min(max(app.aw_t,0),app.Condition(1).dur)*app.RsFs) + [1 0];
			for ci = 1:app.nconditions
				app.CN_avg_lines(ci).XData = app.CFs;
				app.CN_avg_lines(ci).YData = mean(app.Condition(ci).cn_sout_contra(:,idx(1):idx(2)),2);
				app.CN_avg_lines(ci).Visible = 'on';
			end
			app.CN_avg_axes.XLim = app.CF_range;
			app.CN_avg_axes.YLimMode = 'auto';
			app.CN_avg_axes.YLim(1) = 0;
			if app.stimData(app.stimIdx).UseLogAxes
				app.CN_avg_axes.XScale = 'log';
			else
				app.CN_avg_axes.XScale = 'linear';
			end
			app.CN_avg_axes.Visible = 'on';
			app.CN_avg_axes.UserData = sprintf('AN Ave. Rate (%.3g to %.3g s)',app.aw_t);
		end


		function next_IC_avg(app,~,~)
			% User clicked on IC average plot.  Replot both conditions.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			% clicks_ic_avg_rate = rem(clicks_ic_avg_rate,2) + 1;
			app.clicks_ic_avg_rate = app.be_bs_selection;
			set(app.IC_avg_lines,'Visible','off')
			dur = app.Condition(1).dur;
			idx = round(min(max(app.aw_t,0),dur)*app.RsFs) + [1 0];
			if app.Which_IC == 1 % SFIE model; plot toggles between BE and BS model responses
				app.be_bs_radio_group.Visible = 'on';
				if app.clicks_ic_avg_rate == 1
					for ci = 1:app.nconditions
						% set plot props for Condition(ci).average_ic_sout_BE
						app.IC_avg_lines(ci).XData = app.CFs;
						app.IC_avg_lines(ci).YData = mean(app.Condition(ci).BE_sout_population(:,idx(1):idx(2)),2);
						app.IC_avg_lines(ci).Visible = 'on';
					end
					% set title with BE
					title(app.IC_avg_axes,sprintf('IC BE Ave. Rate (BMF=%g Hz)',app.BMF))
					app.IC_avg_axes.UserData = sprintf('IC BE Ave. Rate (BMF=%g Hz) (%.3g to %.3g s)',app.BMF,app.aw_t);
				else
					for ci = 1:app.nconditions
						% set plot props for Condition(ci).average_ic_sout_BS
						app.IC_avg_lines(ci).XData = app.CFs;
						app.IC_avg_lines(ci).YData = mean(app.Condition(ci).BS_sout_population(:,idx(1):idx(2)),2);
						app.IC_avg_lines(ci).Visible = 'on';
					end
					% set title with BS
					title(app.IC_avg_axes,sprintf('IC BS Ave. Rate (BMF=%g Hz)',app.BMF))
					app.IC_avg_axes.UserData = sprintf('IC BS Ave. Rate (BMF=%g Hz) (%.3g to %.3g s)',app.BMF,app.aw_t);
				end
			elseif app.Which_IC == 2 % AN_ModFilt model (models only Band-Enhanced cells)
				app.be_bs_radio_group.Visible = 'off';
				for ci = 1:app.nconditions
					% set plot props for Condition(ci).average_ic_sout_BE
					app.IC_avg_lines(ci).XData = app.CFs;
					app.IC_avg_lines(ci).YData = mean(app.Condition(ci).BE_sout_population(:,idx(1):idx(2)),2);
					app.IC_avg_lines(ci).Visible = 'on';
				end
				% set title with BE
				title(app.IC_avg_axes,sprintf('IC BE Ave. Rate (BMF=%g Hz)',app.BMF))
				app.IC_avg_axes.UserData = sprintf('IC BE Ave. Rate (BMF=%g Hz) (%.3g to %.3g s)',app.BMF,app.aw_t);
			end
			% Set other props.
			app.IC_avg_axes.XLim = app.CF_range;
			app.IC_avg_axes.YLimMode = 'auto';
			app.IC_avg_axes.YLim(1) = 0;
			if app.stimData(app.stimIdx).UseLogAxes
				app.IC_avg_axes.XScale = 'log';
			else
				app.IC_avg_axes.XScale = 'linear';
			end
			app.IC_avg_axes.Visible = 'on';
		end


		function next_wave_w(app,~,~)
			% User clicked on wide stimulus waveform plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			% clicks_wave_w = rem(clicks_wave_w,nconditions) + 1;
			app.clicks_wave_w = app.clicks_wide;
			time = (0:app.Condition(app.clicks_wave_w).N-1)/app.Fs;
			app.stim_wav_line_w.XData = time;
			app.stim_wav_line_w.YData = app.Condition(app.clicks_wave_w).stimulus;
			app.stim_wav_line_w.Color = app.colors{app.clicks_wave_w};
			% 			app.wav_axes_w.XLim = [0,app.Condition(app.clicks_wave_w).dur2];
			app.wav_axes_w.XLim = [0,app.DurMax];
			app.wav_axes_w.YLimMode = 'auto';
			app.wav_axes_w.Visible = 'on';
			fmt = 'Waveform: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.wav_axes_w,sprintf(fmt,app.stim_wav_line_w.Color,app.clicks_wave_w))
		end


		function next_specgram_w(app,~,~)
			% User clicked on wide spectrogram plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			% 		clicks_specgram_w = rem(clicks_specgram_w,nconditions) + 1;
			app.clicks_specgram_w = app.clicks_wide;
			app.specgram_surf_w.XData = app.Condition(app.clicks_specgram_w).tsg;
			app.specgram_surf_w.YData = app.Condition(app.clicks_specgram_w).fsg;
			app.specgram_surf_w.CData = abs(app.Condition(app.clicks_specgram_w).specgram);
			app.specgram_surf_w.ZData = zeros(size(app.specgram_surf_w.CData));
			app.specgram_axes_w.XLim = [0,app.DurMax];
			app.specgram_axes_w.YLim = [0.9 1.1].*app.CF_range;
			app.specgram_axes_w.Visible = 'on';
			fmt = 'Spectrogram: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			str = sprintf(fmt,app.colors{app.clicks_specgram_w},app.clicks_specgram_w);
			title(app.specgram_axes_w,str)
		end


		function next_VIHC_w(app,~,~)
			% User clicked on wide VIHC plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			% clicks_vihc_timefreq = rem(clicks_vihc_timefreq,nconditions) + 1;
			app.clicks_vihc_timefreq = app.clicks_wide;
			data = app.Condition(app.clicks_vihc_timefreq).VIHC_population;
			app.VIHC_surf_w.XData = (0:length(data)-1)/app.RsFs;
			app.VIHC_surf_w.YData = app.CFs;
			app.VIHC_surf_w.ZData = zeros(size(data));
			app.VIHC_surf_w.CData = 1000*data;
			app.VIHC_axes_w.XLim = [0,app.DurMax];
			app.VIHC_axes_w.YLim = app.CF_range;
			% Set the title.
			fmt = 'IHC Model: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.VIHC_axes_w,sprintf(fmt,app.colors{app.clicks_vihc_timefreq},app.clicks_vihc_timefreq))
			% Make the axes and its colorbar visible.
			app.VIHC_axes_w.Visible = 'on';
			app.VIHC_cb.Visible = 'on';
		end


		function next_AN_model_w(app,~,~)
			% User clicked on wide AN model plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			% clicks_an_timefreq_w = rem(clicks_an_timefreq_w,nconditions) + 1;
			app.clicks_an_timefreq_w = app.clicks_wide;

			if app.Which_AN == 1
				data = app.Condition(app.clicks_an_timefreq_w).an_sout_population;
				app.AN_surf_w.XData = (0:length(data)-1)/app.RsFs;
			elseif app.Which_AN == 2
				% dur = Condition(clicks_an_timefreq_w).dur;
				% data = Condition(clicks_an_timefreq_w).an_sout_population_plot(:,floor(0.01*Fs/16):floor(dur*Fs/16));
				% plotcolor_w.XData = (0:length(data)-1)*16/Fs;

				% an_sout_population_plot data has a sample rate = Fs/16, and is
				% delayed by 10 ms.
				Fs2 = app.Fs/16;
				data = app.Condition(app.clicks_an_timefreq_w).an_sout_population_plot;
				app.AN_surf_w.XData = (0:length(data)-1)/Fs2 - 10e-3; % shift by 10 ms
			end
			app.AN_surf_w.YData = app.CFs;
			app.AN_surf_w.ZData = zeros(size(data));
			app.AN_surf_w.CData = data;
			app.AN_axes_w.XLim = [0,app.DurMax];
			app.AN_axes_w.YLim = app.CF_range;

			fmt = 'AN Model: {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.AN_axes_w,sprintf(fmt,app.colors{app.clicks_an_timefreq_w},app.clicks_an_timefreq_w))
			app.AN_axes_w.Visible = 'on';
			app.AN_model_cb_w.Visible = 'on';
		end


		function next_IC_model_w(app,~,~)
			% User clicked on wide IC model plot.  Switch to next condition.
			if ~strcmp(app.UIFigure.SelectionType,'normal')
				% Abort if not a normal (left) click.
				return
			end
			% 		clicks_ic_timefreq_wide = rem(clicks_ic_timefreq_wide,nconditions) + 1;
			app.clicks_ic_timefreq_w = app.clicks_wide;
			data = app.Condition(app.clicks_ic_timefreq_w).BE_sout_population;
			app.IC_surf_w.XData = (0:length(data)-1)/app.RsFs;
			app.IC_surf_w.YData = app.CFs;
			app.IC_surf_w.ZData = zeros(size(data));
			app.IC_surf_w.CData = data;
			app.IC_axes_w.XLim = [0,app.DurMax];
			app.IC_axes_w.YLim = app.CF_range;
			max_val = max(arrayfun(@(x)max(x.BE_sout_population(:)),app.Condition(1:app.nconditions)));
			caxis(app.IC_axes_w,[0 max_val])
			% Set the title.
			fmt = 'IC Model (BE Cell): {\\color[rgb]{%.2g,%.2g,%.2g}C%d}';
			title(app.IC_axes_w,sprintf(fmt,app.colors{app.clicks_ic_timefreq_w},app.clicks_ic_timefreq_w))
			% Make the axes and its colorbar visible.
			app.IC_axes_w.Visible = 'on';
			app.IC_model_cb_w.Visible = 'on';
		end


		function next_wide_plots(app,~,~)
			app.UIFigure.Pointer = 'watch';
			% Switch to next condition for all wide plots.
			app.clicks_wide = rem(app.clicks_wide,app.nconditions) + 1;

			% Set up an array of function handles and run them in turn.
			fcns = {@app.next_wave_w, @app.next_specgram_w,...
				@app.next_VIHC_w, @app.next_AN_model_w,...
				@app.next_IC_model_w};
			for fi = 1:length(fcns)
				% Check for abort.
				if isvalid(app.Progressbar) && app.Progressbar.CancelRequested
					close(app.Progressbar)
					return
				end
				fcns{fi}()
				drawnow
			end
			app.UIFigure.Pointer = 'arrow';
		end


		function clear_standard_plots(app)
			% Clear narrow waveform plot.
			set(app.analysis_window_lines,'Visible','off')
			app.stim_wav_line.XData = NaN;
			app.stim_wav_line.YData = NaN;
			app.wav_axes_n.Visible = 'on';
			title(app.wav_axes_n,'Waveform:')

			% Clear spectrogram plot.
			app.specgram_surf.XData = NaN;
			app.specgram_surf.YData = NaN;
			app.specgram_surf.CData = NaN;
			app.specgram_surf.ZData = NaN;
			title(app.specgram_axes_n,'Spectrogram:')

			% Clear spectrum plot.
			app.spec_line.XData = NaN;
			app.spec_line.YData = NaN;
			title(app.spec_axes_n,'Spectrum:')

			% Clear AN plot.
			app.AN_surf_n.XData = NaN;
			app.AN_surf_n.YData = NaN;
			app.AN_surf_n.ZData = NaN;
			app.AN_surf_n.CData = NaN;
			title(app.AN_axes_n,'AN Model:')

			% Clear IC plot.
			app.IC_surf_n.XData = NaN;
			app.IC_surf_n.YData = NaN;
			app.IC_surf_n.ZData = NaN;
			app.IC_surf_n.CData = NaN;
			title(app.IC_axes_n,'IC Model (BE Cell):')

			% Clear AN avg. plot.
			for ci = 1:2
				app.AN_avg_lines(ci).XData = NaN;
				app.AN_avg_lines(ci).YData = NaN;
			end

			% Clear CN avg. plot.
			for ci = 1:2
				app.CN_avg_lines(ci).XData = NaN;
				app.CN_avg_lines(ci).YData = NaN;
			end

			% Clear IC avg. plot.
			for ci = 1:2
				% set plot props for Condition(ci).average_ic_sout_BE
				app.IC_avg_lines(ci).XData = NaN;
				app.IC_avg_lines(ci).YData = NaN;
			end
		end


		function clear_wide_plots(app)
			% Switch to next condition for all wide plots.
			app.clicks_wide = rem(app.clicks_wide,app.nconditions) + 1;

			% Clear wide waveform.
			app.stim_wav_line_w.XData = NaN;
			app.stim_wav_line_w.YData = NaN;
			title(app.wav_axes_w,'Waveform:')

			% Clear wide spectrogram.
			app.specgram_surf_w.XData = NaN;
			app.specgram_surf_w.YData = NaN;
			app.specgram_surf_w.CData = NaN;
			app.specgram_surf_w.ZData = NaN;
			title(app.specgram_axes_w,'Spectrogram:')

			% Clear wide VIHC plot.
			app.VIHC_surf_w.XData = NaN;
			app.VIHC_surf_w.YData = NaN;
			app.VIHC_surf_w.ZData = NaN;
			app.VIHC_surf_w.CData = NaN;
			title(app.VIHC_axes_w,'IHC Model:')

			% Clear wide AN model plot.
			app.AN_surf_w.XData = NaN;
			app.AN_surf_w.YData = NaN;
			app.AN_surf_w.ZData = NaN;
			app.AN_surf_w.CData = NaN;
			title(app.AN_axes_w,'AN Model:')

			% Clear wide IC model plot.
			app.IC_surf_w.XData = NaN;
			app.IC_surf_w.YData = NaN;
			app.IC_surf_w.ZData = NaN;
			app.IC_surf_w.CData = NaN;
			title(app.IC_axes_w,'IC Model:')

		end


		function sel_line_down(app,~,~,k)
			% User clicked on analysis window vertical line k (k = 1 or 2) on
			% stimulus waveform plot.  Set callback functions to track motion of
			% pointer and detect when mouse button is released.
			app.move_both = strcmp(app.UIFigure.SelectionType,'extend');
			app.selected_line = k;
			app.UIFigure.WindowButtonMotionFcn = @app.sel_line_move;
			app.UIFigure.WindowButtonUpFcn = @app.sel_line_up;
			app.sel_line_move()
		end


		function sel_line_move(app,~,~)
			% Update location of analysis window vertical line(s) and call
			% function to update all affected graphs.

			% Get current location of mouse pointer.
			cpx = app.wav_axes_n.CurrentPoint(1,1);

			% Update analysis window line(s).
			if app.move_both
				d = diff(app.aw_t);
				app.aw_t(app.selected_line) = cpx;
				if app.selected_line == 1
					app.aw_t(2) = cpx + d;
				else
					app.aw_t(1) = cpx - d;
				end
			else
				if app.selected_line == 1 && cpx > app.aw_t(2) - 0.005 || ...
						app.selected_line == 2 && cpx < app.aw_t(1) + 0.005
					return
				end
				app.aw_t(app.selected_line) = cpx;
			end
			app.analysis_window_lines(1).Value = app.aw_t(1);
			app.analysis_window_lines(2).Value = app.aw_t(2);

			% Update plots to reflect new analysis window.
			app.update_plots_with_selection()
		end

		function sel_line_up(app,~,~)
			% Unset callback functions that track mouse pointer movement.
			app.UIFigure.WindowButtonMotionFcn = '';
			app.UIFigure.WindowButtonUpFcn = '';
		end

		function update_plots_with_selection(app)
			% This method is not a callback, but is called from several places
			% where the plots affected by the analysis window must change.

			app.AnalysisWindowEditField.Value = app.aw_t(1);
			app.toEditField.Value = app.aw_t(2);

			% Adjust AN and IC model plot axes limits.
			app.AN_axes_n.XLim = app.aw_t;
			app.IC_axes_n.XLim = app.aw_t;

			% Hide line plots.  They will be revealed below.
			set(app.AN_avg_lines,'Visible','off')
			set(app.CN_avg_lines,'Visible','off')
			set(app.IC_avg_lines,'Visible','off')
			app.CN_avg_axes.Visible = 'off';

			% Recompute averages of AN, CN, and IC model results over analysis
			% window and update the plots.
			dur = min(arrayfun(@(x)x.dur,app.Condition(1:app.nconditions)));
			idx = round(min(max(app.aw_t,0),dur)*app.RsFs) + [1 0];
			for ci = 1:app.nconditions
				app.AN_avg_lines(ci).YData = mean(app.Condition(ci).an_sout_population(:,idx(1):idx(2)),2);
				app.AN_avg_lines(ci).Visible = 'on';
				app.AN_avg_axes.YLimMode = 'auto';
				app.AN_avg_axes.YLim(1) = 0;
				app.AN_avg_axes.UserData = sprintf('AN Ave. Rate (%.3g to %.3g s)',app.aw_t);

				if app.Which_IC == 1
					app.CN_avg_lines(ci).YData = mean(app.Condition(ci).cn_sout_contra(:,idx(1):idx(2)),2);
					app.CN_avg_lines(ci).Visible = 'on';
					app.CN_avg_axes.YLimMode = 'auto';
					app.CN_avg_axes.YLim(1) = 0;
					app.CN_avg_axes.UserData = sprintf('AN Ave. Rate (%.3g to %.3g s)',app.aw_t);
					app.CN_avg_axes.Visible = 'on';
				end

				if app.Which_IC == 1 && app.be_bs_selection == 2
					app.IC_avg_lines(ci).YData = mean(app.Condition(ci).BS_sout_population(:,idx(1):idx(2)),2);
					app.IC_avg_axes.UserData = sprintf('IC BS Ave. Rate (BMF=%g Hz) (%.3g to %.3g s)',app.BMF,app.aw_t);
				else
					app.IC_avg_lines(ci).YData = mean(app.Condition(ci).BE_sout_population(:,idx(1):idx(2)),2);
					app.IC_avg_axes.UserData = sprintf('IC BE Ave. Rate (BMF=%g Hz) (%.3g to %.3g s)',app.BMF,app.aw_t);
				end
				app.IC_avg_lines(ci).Visible = 'on';
				app.IC_avg_axes.YLimMode = 'auto';
				app.IC_avg_axes.YLim(1) = 0;
			end
		end


		function select_IC_model(app,~,~)
			% Allow user to select IC model from examples in Nelson & Carney 2004,
			% Fig. 8.  Flag that model type has changed allows past values to be
			% retained.

			previous_Which_IC = app.Which_IC;
			app.Which_IC = app.IC_model_popup.Value;
			IC_model_just_changed = app.Which_IC ~= previous_Which_IC;

			switch app.Which_IC
				case 1 % SFIE model
					[app.IC_data_edit{1}.Visible] = deal('on');
					[app.IC_data_labels{1}.Visible] = deal('on');
					[app.IC_data_edit{2}.Visible] = deal('off');
					[app.IC_data_labels{2}.Visible] = deal('off');
					[app.IC_data_edit{3}.Visible] = deal('off');
					[app.IC_data_labels{3}.Visible] = deal('off');

					% Use last value, unless model type has just been changed.
					if IC_model_just_changed
						% Initial default when switching to SFIE model.
						app.BMF = 100;
						app.IC_data_edit{1}.Value = num2str(app.BMF);
					end
					app.BMF = str2double(app.IC_data_edit{1}.Value);
					app.IC_model_popup.Tooltip = app.IC_model_credits{1};

				case 2 % AN_ModFilt model
					[app.IC_data_edit{2}.Visible] = deal('on');
					[app.IC_data_labels{2}.Visible] = deal('on');
					[app.IC_data_edit{1}.Visible] = deal('off');
					[app.IC_data_labels{1}.Visible] = deal('off');
					[app.IC_data_edit{3}.Visible] = deal('off');
					[app.IC_data_labels{3}.Visible] = deal('off');

					% Use last value, unless model type has just been changed.
					if IC_model_just_changed
						% Initial default when switching to AN_modfilt model.
						app.BMF = 100;
						app.IC_data_edit{2}.Value = num2str(app.BMF);
					end
					app.BMF = str2double(app.IC_data_edit{2}.Value);
					app.IC_model_popup.Tooltip = app.IC_model_credits{2};

			end
		end


		function select_AN_model(app,~,~)
			% Select the AN model to be used.
			app.Which_AN = app.AN_model_popup.Value;
		end

		function upload(app,~,~,k)
			% These are all the file types that can be read by audioread.
			audio_file_types = ['*.wav;*.flac;*.ogg;*.au;*.aiff;*.aif;',...
				'*.aifc;*.mp3;*.m4a;*.mp4;*.m4v'];
			if ~ismac % plus these on Windows and Linux
				audio_file_types = [audio_file_types,'*.wmv;*.avi'];
			end
			file_filter = {audio_file_types,'Audio files (*.wav, etc.)';...
				'*.mat','MAT-files (*.mat)';...
				'*.xls;*.xlsx','Excel files (*.xls, *.xlsx)';...
				'*.csv;*.txt','Text files (*.csv, *.txt)'};

			% Prompt user for file name and read file.
			app.bring_mac_matlab_to_front()
			[f,p] = uigetfile(file_filter,'Select an audio file:',app.AudioFileFolder);
			figure(app.UIFigure)
			if isequal(f,0)
				return
			end
			app.AudioFileFolder = p;
			filepath = fullfile(p,f);
			idx = k + 1;
			app.stimData(1).Params(idx).Value = f;
			app.stimData(1).Params(idx).Data = filepath;
			app.stimData(1).Params(idx).ValueH.Value = f;
			[~,~,ext] = fileparts(f);
			switch lower(ext)
				case '.mat'
					% Get contents of MAT-file and the names of the variables.
					data = load(filepath);
					var_names = fieldnames(data);

					% Find sample rate.  Variable must be a numeric scalar.
					% First look for variables named fs or Fs.  If neither is
					% present, then look for any other numeric scalar, but there
					% must be only one.
					is_fs = ismember('fs',var_names) && isnumeric(data.fs);
					is_Fs = ismember('Fs',var_names) && isnumeric(data.Fs);
					is_scalar = cellfun(@(x)isnumeric(data.(x)) && ...
						isscalar(data.(x)),var_names);
					if is_fs
						fs = data.fs;
					elseif is_Fs
						fs = data.Fs;
					elseif sum(is_scalar) == 1
						fs = data.(var_names{is_scalar});
					else
						uialert(app.UIFigure,['Unable to find a variable ',...
							'containing the sample rate.'],'Error')
						return
					end

					% Find audio data.  Variable must be numeric and a vector of
					% length greater than 1, or a matrix with two columns and
					% more than two rows, or a matrix with two rows and more
					% than two columns.
					is_vec = cellfun(@(x)isnumeric(data.(x)) && ...
						isvector(data.(x)) && ...
						length(data.(x)) > 1,var_names);
					is_tall_mat = cellfun(@(x)isnumeric(data.(x)) && ...
						ismatrix(data.(x)) && ...
						size(data.(x),2) == 2 && ...
						size(data.(x),1) > 2,var_names);
					is_wide_mat = cellfun(@(x)isnumeric(data.(x)) && ...
						ismatrix(data.(x)) && ...
						size(data.(x),1) == 2 && ...
						size(data.(x),2) > 2,var_names);
					if sum(is_vec) == 1
						audio = data.(var_names{is_vec})(:);
					elseif sum(is_tall_mat) == 1
						audio = mean(data.(var_names{is_tall_mat}),2);
					elseif sum(is_wide_mat) == 1
						audio = mean(data.(var_names{is_wide_mat}),1).';
					else
						uialert(app.UIFigure,['Unable to find a variable ',...
							'containing the audio data.'],'Error')
						return
					end

					% Store fs and audio data.
					app.stimData(1).Params(idx).Waveform{1} = fs;
					app.stimData(1).Params(idx).Waveform{2} = audio;

				case {'.xls','.xlsx'}
				case {'.csv','.txt'}
				otherwise
					[app.stimData(1).Params(idx).Waveform{1:2}] = audioread(filepath);
					app.stimData(1).Params(idx).Waveform{1} = ...
						mean(app.stimData(1).Params(idx).Waveform{1},2);
			end

		end


		function make_exportable(app,ax,plot_type)
			%make_exportable: Click on a context menu to export the plot to a file.
			%
			%  Syntax:
			%    app.make_exportable(AXES_HANDLE)

			% Author: Doug Schwarz
			% Email: douglas.schwarz@rochester.edu
			% Date: 25 March 2021

			if nargin < 3 || isempty(plot_type)
				plot_type = 'line';
			end

			parent_fig = app.UIFigure;
			export_cm = uicontextmenu('Parent',parent_fig);
			uimenu(export_cm,'Label','Export Plot to Vector Graphics…',...
				'Callback',{@export_plot_vector,ax});
			uimenu(export_cm,'Label','Export Plot to Bitmap Graphics…',...
				'Callback',{@export_plot_bitmap,ax});
			uimenu(export_cm,'Label','Export Plot Data to MATLAB MAT-file…',...
				'Separator','on',...
				'Callback',{@export_data_mat,ax});
			if strcmp(plot_type,'line')
				uimenu(export_cm,'Label','Export Plot Data to Excel file…',...
					'Callback',{@export_data_excel,ax});
				uimenu(export_cm,'Label','Export Plot Data to CSV file…',...
					'Callback',{@export_data_csv,ax});
			elseif strcmp(plot_type,'surface')
				uimenu(export_cm,'Label','Export Plot Data to CSV file…',...
					'Callback',{@export_surface_data_csv,ax});
				uimenu(export_cm,'Label','Export Plot Data to binary file…',...
					'Callback',{@export_data_binary,ax});
			end
			set(ax,'UIContextMenu',export_cm)

			function export_plot_vector(~,~,ax)
				persistent already_warned
				if isempty(already_warned)
					already_warned = false;
				end

				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.pdf','Save plot as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end

				parent_fig = ancestor(ax,'figure');
				if ~isdeployed
					figure(parent_fig)
				end

				parent_fig.Pointer = 'watch';
				op = ax.OuterPosition;
				new_panel = uipanel(parent_fig,'Position',[0 0 op(3:4)],'Visible','off');
				new_ax = copyobj(ax,new_panel);
				new_ax.OuterPosition(1:2) = [1 1];
				delete(findobj(new_ax,'Tag','delete when exported'))
				lastwarn('','')
				warning('off','MATLAB:print:ContentTypeImageSuggested')
				exportgraphics(new_panel,fullfile(p,f),'ContentType','vector')
				warning('on','MATLAB:print:ContentTypeImageSuggested')
				[~,warnID] = lastwarn;
				if strcmp(warnID,'MATLAB:print:ContentTypeImageSuggested') && ~already_warned
					msg = ['Exporting pseudocolor content to a vectorized format might ',...
						'take a long time to create, or it might contain unexpected results.'];
					uialert(parent_fig,msg,'Warning','Icon','warning')
					already_warned = true;
				end
				delete(new_panel)
				parent_fig.Pointer = 'arrow';
			end

			function export_plot_bitmap(~,~,ax)
				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.png','Save plot as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end

				parent_fig = ancestor(ax,'figure');
				if ~isdeployed
					figure(parent_fig)
				end

				parent_fig.Pointer = 'watch';
				op = ax.OuterPosition;
				new_panel = uipanel(parent_fig,'Position',[0 0 op(3:4)],'Visible','off');
				new_ax = copyobj(ax,new_panel);
				new_ax.OuterPosition(1:2) = [1 1];
				delete(findobj(new_ax,'Tag','delete when exported'))
				exportgraphics(new_panel,fullfile(p,f),'ContentType','image')
				delete(new_panel)
				parent_fig.Pointer = 'arrow';
			end

			function export_data_mat(~,~,ax)
				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.mat','Save data as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end
				% Export data from axes into a mat-file.
				children = flip(ax.Children);
				tags = arrayfun(@(x){x.Tag},children);
				vis = arrayfun(@(x){x.Visible},children);
				children(strcmp(tags,'delete when exported') | strcmp(vis,'off')) = [];
				num_children = length(children);
				if num_children == 1 && strcmp(children.Type,'surface')
					X = children.XData;
					Y = children.YData;
					Z = children.CData;
					save(fullfile(p,f),'X','Y','Z')
				elseif num_children == 1 && strcmp(children.Type,'line')
					X = children.XData;
					Y = children.YData;
					save(fullfile(p,f),'X','Y')
				elseif num_children == 2 && all(strcmp({children.Type},'line'))
					X1 = children(1).XData;
					Y1 = children(1).YData;
					X2 = children(2).XData;
					Y2 = children(2).YData;
					save(fullfile(p,f),'X1','Y1','X2','Y2')
				end
			end

			function export_data_excel(~,~,ax)
				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.xls','Save data as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end
				% Export data from axes into an Excel file.
				children = flip(ax.Children);
				tags = arrayfun(@(x){x.Tag},children);
				vis = arrayfun(@(x){x.Visible},children);
				children(strcmp(tags,'delete when exported') | strcmp(vis,'off')) = [];
				num_children = length(children);
				if num_children == 1 && strcmp(children.Type,'line')
					out = [children.XData(:),children.YData(:)];
					writematrix(out,fullfile(p,f))
				elseif num_children == 2 && all(strcmp({children.Type},'line'))
					X1 = children(1).XData;
					Y1 = children(1).YData;
					X2 = children(2).XData;
					Y2 = children(2).YData;
					N1 = length(X1);
					N2 = length(X2);
					nrows = max(N1,N2);
					out = cell(nrows,4);
					out(1:N1,1:2) = num2cell([X1(:),Y1(:)]);
					out(1:N2,3:4) = num2cell([X2(:),Y2(:)]);
					writecell(out,fullfile(p,f))
				end
			end

			function export_data_csv(~,~,ax)
				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.csv','Save data as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end
				% Export data from axes into a CSV file.
				children = flip(ax.Children);
				tags = arrayfun(@(x){x.Tag},children);
				vis = arrayfun(@(x){x.Visible},children);
				children(strcmp(tags,'delete when exported') | strcmp(vis,'off')) = [];
				num_children = length(children);
				if num_children == 1 && strcmp(children.Type,'line')
					out = [children.XData(:),children.YData(:)];
					writematrix(out,fullfile(p,f))
				elseif num_children == 2 && all(strcmp({children.Type},'line'))
					X1 = children(1).XData;
					Y1 = children(1).YData;
					X2 = children(2).XData;
					Y2 = children(2).YData;
					N1 = length(X1);
					N2 = length(X2);
					nrows = max(N1,N2);
					out = cell(nrows,4);
					out(1:N1,1:2) = num2cell([X1(:),Y1(:)]);
					out(1:N2,3:4) = num2cell([X2(:),Y2(:)]);
					writecell(out,fullfile(p,f))
				end
			end

			function export_surface_data_csv(~,~,ax)
				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.csv','Save data as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end
				% Export data from axes into a CSV file.
				children = flip(ax.Children);
				tags = arrayfun(@(x){x.Tag},children);
				vis = arrayfun(@(x){x.Visible},children);
				children(strcmp(tags,'delete when exported') | strcmp(vis,'off')) = [];
				X = children.XData;
				Y = children.YData;
				Z = children.CData;
				fid = fopen(fullfile(p,f),'wt');
				fprintf(fid,'%d\n',length(X));
				fprintf(fid,'%g\n',X);
				fprintf(fid,'%d\n',length(Y));
				fprintf(fid,'%g\n',Y);
				fprintf(fid,'%d %d\n',size(Z));
				fprintf(fid,'%g\n',Z);
				fclose(fid);
			end

			function export_data_binary(~,~,ax)
				app.bring_mac_matlab_to_front()
				if isunix
					def_dir = fullfile('~','Downloads',filesep);
				else
					def_dir = fullfile(getenv('userprofile'),'Downloads',filesep);
				end
				[f,p] = uiputfile('*.dat','Save data as:',def_dir);
				figure(app.UIFigure)
				if isequal(f,0)
					return
				end
				% Export data from axes into a binary file.
				children = flip(ax.Children);
				tags = arrayfun(@(x){x.Tag},children);
				vis = arrayfun(@(x){x.Visible},children);
				children(strcmp(tags,'delete when exported') | strcmp(vis,'off')) = [];
				X = children.XData;
				Y = children.YData;
				Z = children.CData;
				fid = fopen(fullfile(p,f),'w');
				fwrite(fid,length(X),'double');
				fwrite(fid,X,'double');
				fwrite(fid,length(Y),'double');
				fwrite(fid,Y,'double');
				fwrite(fid,size(Z),'double');
				fwrite(fid,Z,'double');
				fclose(fid);
			end

		end


		function bring_mac_matlab_to_front(app)
			if ismac
				if isdeployed
					parent_proc = mfilename;
				else
					parent_proc = sprintf('MATLAB_R%s',version('-release'));
				end
				fmt = 'osascript -e "tell application \\\"%s\\\" to activate"';
				system(sprintf(fmt,parent_proc));
			end

		end


		function fix_axes_width(~,ax,width)
			% InnerPosition apparently includes colorbar so adjust width.
			drawnow
			if ax.InnerPosition(3) ~= width
				ax.InnerPosition(3) = 2*width - ax.InnerPosition(3);
			end
		end

	end % Methods (Access = private)


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, use_parallel)

			if nargin == 2
				app.UseParallel = use_parallel;
			end

			% Determine whether app is running within MATLAB, as a stand-alone
			% executable, or a web app.
			if isdeployed
				pattern = fullfile('MathWorks','webapps');
				if isempty(strfind(pwd,pattern)) %#ok<STREMP>
					app.is_standalone = true;
				else
					app.is_webapp = true;
				end
			else
				app.is_matlab = true;
			end

			% Center the figure.
			app.UIFigure.Visible = 'off';
			movegui(app.UIFigure,'center')

			% Check if this version of MATLAB supports scrollable uifigures.
			% If so, set the figure to be scrollable, and if not, terminate.
			if ismember('Scrollable',properties(app.UIFigure))
				app.UIFigure.Scrollable = true;
			else
				app.UIFigure.Visible = 'on';
				uialert(app.UIFigure,sprintf( ...
					['In order to have room for the large number of ',...
					'plots generated by UR EAR, the figure must be ',...
					'scrollable and this feature is not supported by ',...
					'the current version of MATLAB.\n\n',...
					'Please install MATLAB R2018b or later, download a ',...
					'stand-alone executable, or use the ',...
					'Web App available at:\n',...
					'https://urhear.urmc.rochester.edu']),...
					'Version Error','CloseFcn',@close_app);
				return
			end

			% Callback function that runs when uialert above is closed.
			function close_app(~,~)
				delete(app.UIFigure)
			end

			% Use app's folder as default for finding sound files.
			app.mypath = fileparts(mfilename('fullpath'));

			% Create stimulus parameters and panels.
			createStimData(app)
			createStimParamPanels(app)

			% Initialize any controls that need it.  This has to be done here
			% because prior to this the affected controls do not exist.
			%--------------------------------------------------------------------------
			% Initialize whether the frequency control is displayed.
			app.show_freqs(app.stimData(8).Params(7).ValueH)

			% Set TIN SNR value control to be inactive so you can't type in it.
			app.stimData(4).Params(6).ValueH.Editable = 'off';

			% Set Schroeder C2 value control to be inactive so you can't type in it.
			app.stimData(11).Params(7).ValueH.Editable = 'off';

			% Initialize SNR field.
			app.parse_TIN_BW()

			% Display the parameter panel for the initial stimulus selection.
			app.stimData(app.stimIdx).Panel.Visible = 'on';

			% Create panels and their contents.
			createModelParamPanel(app)
			createNarrowAxes(app)
			createWideAxes(app)

			% Create sound player controls.
			createSoundPlayers(app)
			drawnow

			app.UIFigure.Visible = 'on';
			drawnow

			% Hack to work around bug that causes bottom of figure to be off-screen.
			if ~isdeployed
				app.UIFigure.Resize = 'on';
				app.UIFigure.Position(2) = 1;
				drawnow
				app.UIFigure.Resize = 'off';
			end

			% "Randomize" the random number generator seed.
			rng('shuffle')

			% Enable Run button.
			app.RunButton.Enable = 'on';
			drawnow

			% Set close function to delete parallel pool.
			app.UIFigure.CloseRequestFcn = @close_fig;

			function close_fig(~,~)
				if app.UseParallel && ~isempty(app.ParPool)
					uiprogressdlg(app.UIFigure,...
						'Title','Please Wait',...
						'Message','Deleting parallel pool…',...
						'Indeterminate','on');
					delete(app.ParPool)
				end
				delete(app.UIFigure)
			end

			% Force figure to front.
			figure(app.UIFigure)

		end

		% Value changed function: StimulusTypeDropDown
		function select_stim_type(app, event)
			value = app.StimulusTypeDropDown.Value;
			% Select which stimulus type to use and make that panel visible.

			% Get the stimulus index.
			app.stimIdx = value;

			% Make all stimulus parameter panels invisible.
			all_stim_panels = [app.stimData.Panel];
			[all_stim_panels.Visible] = deal('off');

			% Make the chosen stimulus parameter panel visible.
			all_stim_panels(app.stimIdx).Visible = 'on';

			% Enable Run button, disable Abort button and set pointer back to
			% 'arrow'.  This is done in case there is an error in a model run
			% and the Run button is left disabled.
			% 			app.RunButton.Enable = 'on';
			app.UIFigure.Pointer = 'arrow';

			% Reset Hold state.
			app.analysis_window_hold = false;
			app.HoldButton.Value = false;
		end

		% Button pushed function: ManualButton
		function ManualButtonPushed(app, event)
			% Get render URL of manual and pass to web().
			project_node_id_on_osf = '6bsnt';
			try
				attr = osfdir(project_node_id_on_osf);
			catch err
				uialert(app.UIFigure,err.message,'Error');
				return
			end
			filenames = {attr.name};
			index = find(strcmp(filenames,'manual_UR_EAR_2022a.pdf'));
			if isempty(index)
				uialert(app.UIFigure,'manual_UR_EAR_2022a.pdf not found.','Error');
			else
				web(attr(index).links.render)
			end
		end

		% Button pushed function: FAQButton
		function FAQButtonPushed(app, event)
			web('https://osf.io/6bsnt/wiki/FAQ/')
		end

		% Button pushed function: ContactButton
		function ContactButtonPushed(app, event)
			% Specify contents of email.
			name = 'Laurel Carney';
			email = 'laurel.carney@rochester.edu';
			subject = 'UR EAR question';
			body_cell = {['We are interested in how people are using UR EAR ',...
				'and would appreciate you taking the time to answer a few brief ',...
				'survey questions:'],...
				['1. What type of purpose/project are you pursuing with UR EAR? ',...
				'(e.g., psychophysics, physiology, neuroscience?)'],...
				'2. What is your impression of the current UR EAR web app?',...
				'3. Do you have any recommendations for changes?',...
				'Your question for us:'};
			body = sprintf('%s\n\n',body_cell{:});
			% url = sprintf('mailto:%s (%s)?subject=%s',email,name,subject);
			% url = sprintf('mailto:%s %%3c%s%%3e?subject=%s',name,email,subject);
			url = sprintf('mailto:%s <%s>?subject=%s&body=%s',name,email,...
				subject,body);
			% Replace '<' with '%3c', '>' with '%3e', spaces with '%20', and
			% newlines (char(10)) with '%0a'.
			url = replace(url,{'<','>',' ',newline},{'%3c','%3e','%20','%0a'});

			% Create the email dialog box.  The clipboard function does not work
			% from a web app so use uiemaildlg instead of uiconfirm.
			if app.is_webapp
				uiemaildlg(app.UIFigure)
			else
				msg = sprintf('Send an email to Laurel Carney at\nlaurel.carney@rochester.edu');
				selection = uiconfirm(app.UIFigure,msg,'Contact Us!',...
					'Options',{'Open a new email','Copy address to clipboard','Cancel'},...
					'Icon','email_icon.png');
				switch selection
					case 'Open a new email'
						web(url)
					case 'Copy address to clipboard'
						clipboard('copy','laurel.carney@rochester.edu')
				end
			end

			function uiemaildlg(fig)
				% Make a "send email" dialog box.

				% Create cover for figure.
				figpos = fig.Position;
				figpos(4) = 2140;
				cover_fig = cover_uifigure(fig,figpos);

				% Create dialog box panel.
				wh = [300 262];
				fp = fig.Position;
				pos = [fp(3)/2-wh(1)/2,2140-100-wh(2),wh];
				panel = uipanel(fig,...
					'Position',pos,...
					'Title','  Contact Us!',...
					'TitlePosition','lefttop',...
					'FontSize',14,...
					'FontWeight','bold',...
					'ForegroundColor',[0.25 0.25 0.25]);

				% Create Image.
				% Older versions of MATLAB may not have the uiimage function.
				try
					uiimage(panel,...
						'Position',[20 190 32 32],...
						'ImageSource','email_icon.png');
				catch
					imax = uiaxes(panel,...
						'InnerPosition',[20 190 32 32],...
						'Visible','off');
					imax.Toolbar.Visible = 'off';
					disableDefaultInteractivity(imax)
					email_icon = imread('email_icon.png');
					image(imax,email_icon)
				end

				% Create InfoText.
				uilabel(panel,...
					'Position',[75 190 205 32],...
					'Text','Send an email to Laurel Carney',...
					'FontSize',14);

				% Create Label.
				uilabel(panel,...
					'Position',[30 160 234 22],...
					'Text','You can copy the address from here',...
					'HorizontalAlignment','center',...
					'FontSize',14,...
					'Visible','on');

				% Create CopyLabel.
				uitextarea(panel,...
					'Position',[48 130 198 26],...
					'Value','laurel.carney@rochester.edu',...
					'HorizontalAlignment','center',...
					'FontSize',14,...
					'Editable','off');

				% Create OpenanewemailButton.
				uibutton(panel,'push',...
					'Position',[67 70 160 30],...
					'Text','Compose a new email',...
					'FontSize',14,...
					'ButtonPushedFcn',@OpenanewemailButtonPushed);

				% Create CancelButton.
				uibutton(panel,'push',...
					'Position',[97 20 100 30],...
					'Text','Cancel',...
					'FontSize',14,...
					'ButtonPushedFcn',@CancelButtonPushed);

				% Create orLabel.
				uilabel(panel,...
					'Position',[134 100 26 30],...
					'Text','or',...
					'FontSize',14,...
					'HorizontalAlignment','center');

				% Show the figure after all components are created.
				panel.Visible = 'on';

				% Button pushed function: OpenanewemailButton
				function OpenanewemailButtonPushed(~,~)
					% Delete the panel and gray background.
					delete(panel)
					delete(cover_fig)

					% Generate and post a mailto URL.
					web(url)
				end

				% Button pushed function: CloseButton
				function CancelButtonPushed(~,~)
					% Delete the panel and gray background.
					delete(panel)
					delete(cover_fig)
				end

			end

			function h = cover_uifigure(fig,pos)
				% Create a gray background for a dialog box that covers the
				% whole figure.
				color = [0.1 0.1 0.1];
				alpha = 0.5;

				html_fmt = ['<html>\n',...
					'<head>\n',...
					'<style>\n',...
					'div {\n',...
					'background: rgb(%d, %d, %d, %g);\n',...
					'width: %dpx;\n',...
					'height: %dpx;\n',...
					'}\n',...
					'</style>\n',...
					'</head>\n',...
					'<body><div></div></body>\n',...
					'</html>\n'];

				colori = round(255*color);
				html = sprintf(html_fmt,colori,alpha,pos(3:4));

				h = uihtml(fig,'Position',[1 1 pos(3:4)],...
					'HTMLSource',html);
			end

		end

		% Button pushed function: ResetButton
		function ResetButtonPushed(app, event)
			% User clicked on "Reset" to reset analysis window to default values.

			% Compute new analysis window and position lines.
			if isempty(app.Condition)
				return
			end
			dur1 = app.Condition(1).dur;
			if isempty(app.Condition(2).stimulus)
				dur2 = NaN;
			else
				dur2 = app.Condition(2).dur;
			end
			dur = min(dur1,dur2);
			app.aw_t = [0.1 0.9]*dur;

			% Move lines.
			app.analysis_window_lines(1).Value = app.aw_t(1);
			app.analysis_window_lines(2).Value = app.aw_t(2);

			% Update plots to reflect new analysis window.
			app.analysis_window_hold = false;
			app.HoldButton.Value = false;
			app.AnalysisWindowEditField.Value = app.aw_t(1);
			app.toEditField.Value = app.aw_t(2);
			app.update_plots_with_selection()
		end

		% Button pushed function: HelpButton
		function HelpButtonPushed(app, event)
			bullet = @(str)sprintf('%c %s',8226,str);
			indent = @(str)sprintf('%c %s',8194,str);
			help_str = {[...
				'The two vertical gray lines on the waveform plot allow ',...
				'you to change the Analysis Window which affects the ',...
				'display range of the AN Model and IC Model plots, and ',...
				'averaging span of the AN Ave. Rate, CN Ave. Rate, and ',...
				'IC Ave. Rate plots.'],...
				'',...
				bullet('Click and drag the lines to change the range.'),...
				bullet('Hold down the shift key and drag either line to move both'),...
				indent('lines at the same time, keeping the same span.'),...
				bullet('Enter values directly in the edit boxes.'),...
				bullet('Click on the Hold button to toggle the hold state. Holding'),...
				indent('will preserve the values between Runs.'),...
				'',...
				['The default setting is at the 10% and 90% times ',...
				'of the shorter of the two stimuli. ',...
				'You can reset to the default by clicking on ',...
				'Reset, or the Run button.']};
			uialert(app.UIFigure,help_str,'Analysis Window Help','Icon','')
		end

		% Value changed function: HoldButton
		function HoldButtonValueChanged(app, event)
			app.analysis_window_hold = app.HoldButton.Value;
		end

		% Value changed function: AnalysisWindowEditField
		function AnalysisWindowEditFieldValueChanged(app, event)
			app.aw_t(1) = app.AnalysisWindowEditField.Value;
			app.analysis_window_lines(1).Value = app.aw_t(1);
			app.update_plots_with_selection()
		end

		% Value changed function: toEditField
		function toEditFieldValueChanged(app, event)
			app.aw_t(2) = app.toEditField.Value;
			app.analysis_window_lines(2).Value = app.aw_t(2);
			app.update_plots_with_selection()
		end

		% Value changed function: PlayDownloadSamplerateEditField
		function PlayDownloadSamplerateEditFieldValueChanged(app, event)

			if ~app.is_webapp
				% Create audio players.
				app.players = cell(1,app.nconditions);
				file_fs = app.PlayDownloadSamplerateEditField.Value;
				for ip = 1:app.nconditions
					wav = resample(app.Condition(ip).stimulus,file_fs,app.Fs);
					app.export_waveforms{ip} = {wav,file_fs};
					app.players{ip} = audioplayer(wav,file_fs); %#ok<TNMLP>
					app.players{ip}.StopFcn = {@audio_stop,ip};
					app.players{ip}.TimerPeriod = 0.05;
					app.players{ip}.TimerFcn = {@audio_update,ip};
					audio_update([],[],ip)
					app.media{ip}.panel.Visible = 'on';
				end
				for ip = 2:-1:app.nconditions+1
					app.media{ip}.panel.Visible = 'off';
				end

			else
				% Make html file format.
				html_file_template = {'<!DOCTYPE html>',...
					'<html>',...
					'<audio controls style="width:269px;height:65px">',...
					'<source src="./%s" type="audio/wav">',...
					'</audio>',...
					'</html>'};
				html_file_fmt = sprintf('%s\n',html_file_template{:});

				% Delete previous files.
				if ~isempty(app.C1FileName) && isfile(app.C1FileName)
					delete(app.C1FileName)
				end
				if ~isempty(app.C2FileName) && isfile(app.C2FileName)
					delete(app.C2FileName)
				end

				% Create new file names and increment counters.
				app.C1FileName = sprintf('C1_%d.wav',app.AudioFileCounter);
				app.C2FileName = sprintf('C2_%d.wav',app.AudioFileCounter);
				app.AudioFileCounter = app.AudioFileCounter + 1;

				% Create html files.
				fid = fopen('C1.html','w');
				fprintf(fid,html_file_fmt,app.C1FileName);
				fclose(fid);
				fid = fopen('C2.html','w');
				fprintf(fid,html_file_fmt,app.C2FileName);
				fclose(fid);

				% Create sound files.
				file_fs = app.PlayDownloadSamplerateEditField.Value;
				wav = resample(app.Condition(1).stimulus,file_fs,app.Fs);
				audiowrite(app.C1FileName,wav,file_fs)
				if ~isempty(app.Condition(2).stimulus)
					wav = resample(app.Condition(2).stimulus,file_fs,app.Fs);
					audiowrite(app.C2FileName,wav,file_fs)
				end

				% Set HTML sources.
				app.HTMLC1.HTMLSource = '<html></html>';
				app.HTMLC2.HTMLSource = '<html></html>';
				drawnow
				app.HTMLC1.HTMLSource = fullfile(pwd,'C1.html');
				app.HTMLC2.HTMLSource = fullfile(pwd,'C2.html');

				% Make HTML objects visible.
				app.HTMLC1.Visible = 'on';
				% 				app.HTMLC2.Visible = 'on';

				% Only make play sound control for C2 visible if there are two sounds.
				if app.nconditions == 2
					app.HTMLC2.Visible = 'on';
					app.C2Label.Visible = 'on';
				else
					app.HTMLC2.Visible = 'off';
					app.C2Label.Visible = 'off';
				end

			end

			function audio_stop(~,~,idx)
				% app.media{idx}.play_pause_button.Text = char([0x25b6 0xfe0f]); % play
				app.media{idx}.play_pause_button.Icon = 'play_icon.png'; % play
				audio_update([],[],idx)
			end

			function audio_update(~,~,idx)
				this_time = app.players{idx}.CurrentSample;
				% total_time = app.players{idx}.TotalSamples;
				fs = app.players{idx}.SampleRate;
				% str = sprintf('%d/%d',floor(this_time/fs),floor(total_time/fs));
				str = datestr(floor(this_time/fs)/86400,'MM:SS');
				app.media{idx}.audio_time.Text = str;
			end

		end

		% Button pushed function: RunButton
		function run(app, event)
			% Callback for Run button.  Compute stimuli then call function to
			% compute the model results and plot them.

			% Import all functions in the stimuli package (contents of the
			% +stimuli folder) so they can be called by name, e.g., TIN(),
			% rather than having to use the full package name, e.g., stimuli.TIN().
			import stimuli.*

			% Set pointer to 'watch'.
			app.UIFigure.Pointer = 'watch';

			% Create progress bar.
			app.Progressbar = uiprogressdlg(app.UIFigure,...
				'Title','Please Wait',...
				'Message',sprintf('Generating stimuli…'),...
				'Indeterminate','on',...
				'ShowPercentage','on',...
				'Cancelable','off','CancelText','Abort');

			% Scroll to desired set of plots.
			% 			switch app.AutoscrollButtonGroup.SelectedObject.Text
			% 				case 'to Standard Plots'
			% 					scroll(app.UIFigure,1,app.Panel.Position(2))
			% 				case 'to Wide Plots'
			% 					scroll(app.UIFigure,1,app.WidePlotsPanel.Position(2))
			% 			end
			drawnow

			% Initialize Condition.
			app.Condition = struct('stimulus',cell(1,2));

			% Get a temporary copy of the Params field for the current stimulus
			% type to simplify getting parameters.
			params = app.stimData(app.stimIdx).Params;

			% STIM: Each switch case is for a stimulus type, and gets input
			% parameters from the GUI, and then calls a function to create the
			% waveform(s).  Add a new case (with a new value for stimIdx) by using
			% one of the existing ones as a template.
			% If your new stimus is an audio file, the program does not need to be
			% edited, simply use case 1.
			switch app.stimIdx
				case 1 % Audio File
					% Note: stimulus duration is computed from resampled
					% waveform.  No ramp is applied.

					% Get parameters.
					spl = str2double(params(1).ValueH.Value);
					noise_SNR = str2double(params(4).ValueH.Value);
					ltass_ramp_dur = 0.010; % s

					if isempty(params(2).Data)
						uialert(app.UIFigure,'The first file must not be empty.',...
							'Error')
						app.UIFigure.Pointer = 'arrow';
						close(app.Progressbar)
						return
					end

					% Set number of conditions.
					app.nconditions = 1;

					% Get first audio file, scale to desired RMS level,
					% resample waveform to sampling rate required for AN model,
					% and store in Condition.
					wav1 = app.stimData(1).Params(2).Waveform{1};
					Fs_wav1 = app.stimData(1).Params(2).Waveform{2};
					if ~isnan(spl)
						wav1 = wav1*(app.Pref*10.^(spl/20)/rms(wav1));
					end
					app.Condition(1).stimulus = resample(wav1,app.Fs,Fs_wav1).';
					num_pts1 = length(app.Condition(1).stimulus);
					dur1 = num_pts1/app.Fs;

					% Add LTASS noise.
					if noise_SNR > -Inf
						% Note that ramp is applied after noise is added and no ramp
						% is used for audio file alone.
						% Make wideband LTASS noise (100 Hz - 6 kHz).
						if isnan(spl)
							ltass_SPL = noise_SNR;
						else
							ltass_SPL = spl - noise_SNR;
						end
						% Note: function modified to handle Fs = 100 kHz.
						ltass_noise1 = ltass_noise0(app.Fs,ltass_SPL,num_pts1,1);
						ltass_dur1 = num_pts1/app.Fs;
						ramp1 = tukeywin(num_pts1, 2*ltass_ramp_dur/ltass_dur1);
						ltass_noise1 = ltass_noise1.*ramp1;
						app.Condition(1).stimulus = app.Condition(1).stimulus + ltass_noise1(:).';
					end

					if ~isempty(app.stimData(1).Params(3).Waveform) && ...
							iscell(app.stimData(1).Params(3).Waveform) && ...
							~isempty(app.stimData(1).Params(3).Waveform{1})
						% If second file name is entered, responses to both
						% waveforms will be compared.
						app.nconditions = 2;

						% Get and process second audio file.
						wav2 = app.stimData(1).Params(3).Waveform{1};
						Fs_wav2 = app.stimData(1).Params(3).Waveform{2};
						if ~isnan(spl)
							wav2 = wav2*(app.Pref*10.^(spl/20)/rms(wav2));
						end
						app.Condition(2).stimulus = resample(wav2,app.Fs,Fs_wav2).';
						num_pts2 = length(app.Condition(2).stimulus);
						dur2 = num_pts2/app.Fs;

						% Add LTASS noise.
						if noise_SNR > -Inf
							if isnan(spl)
								ltass_SPL = noise_SNR;
							else
								ltass_SPL = spl - noise_SNR;
							end
							ltass_noise2 = ltass_noise0(app.Fs,ltass_SPL,num_pts2,1);
							ltass_dur2 = num_pts2/app.Fs;
							ramp2 = tukeywin(num_pts2, 2*ltass_ramp_dur/ltass_dur2);
							ltass_noise2 = ltass_noise2.*ramp2;
							app.Condition(2).stimulus = app.Condition(2).stimulus + ltass_noise2(:).';
						end
					else
						dur2 = NaN;
					end
					app.Condition(1).dur = dur1;
					app.Condition(2).dur = dur2;
					% 					dur = min(dur1,dur2);

				case 2 % Noise Band
					% Get parameters.
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					Low_freq = str2double(params(4).ValueH.Value);
					High_freq = str2double(params(5).ValueH.Value);
					app.nconditions = 1; % response to only 1 stimulus
					% Compute stimulus.
					app.Condition(1).stimulus = Noise(dur,rampdur,Low_freq,High_freq,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = NaN;

				case 3 % Notched Noise
					% Get parameters.
					noise_spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					center_freq = str2double(params(4).ValueH.Value);
					delta = str2double(params(5).ValueH.Value);
					rel_bw = str2double(params(6).ValueH.Value);
					tone_spl = str2double(params(7).ValueH.Value);
					app.nconditions = 2;
					% Compute stimuli.  #1 is with tone, #2 has no tone.
					app.Condition(1).stimulus = Notched_Noise(dur,rampdur,center_freq,delta,rel_bw,noise_spl,tone_spl,app.Fs);
					app.Condition(2).stimulus = Notched_Noise(dur,rampdur,center_freq,delta,rel_bw,noise_spl,-Inf,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 4 % TIN (Tone in Noise)
					dur = str2double(params(1).ValueH.Value);
					rampdur = str2double(params(2).ValueH.Value);
					freq = str2double(params(3).ValueH.Value);
					N0 = str2double(params(4).ValueH.Value); % Noise masker spectrum level (dB SPL / Hz)
					Es_N0 = str2double(params(5).ValueH.Value); % Tone level (E/No) ( see Evilsizer et al 2001 )
					switch app.stimData(4).Params(7).Data.BWType
						case 'octaves'
							bw_freqs = freq.*2.^([-0.5 0.5]*app.stimData(4).Params(7).Data.Value);
						case 'hertz'
							bw_freqs = app.stimData(4).Params(7).Data.Value;
					end
					% SNR = str2double(params(6).ValueH.String); % This is already calculated and displayed.
					% if get(handles.binaural, 'Value')
					% 	bin_mode = 2; % 'bin_mode' = binaural mode >> Stay Tuned for Binaural version!
					% else
					% 	bin_mode = 1;
					% end
					bin_mode = 1; % for monaural stimulus, bin_mode = 1
					app.nconditions = 2; % compare responses to 2 stimuli

					% The TIN function will be run to generate a TIN stimulus, not
					% just to calculate SNR.
					calculate_SNR_only = false;
					TINstruct = TIN(dur,rampdur,freq,N0,Es_N0,bin_mode,bw_freqs,app.Fs,calculate_SNR_only);
					switch bin_mode
						case 1
							app.Condition(1).stimulus = TINstruct.pin_TIN; % tone-plus-Noise
							app.Condition(2).stimulus = TINstruct.pin_N; % noise alone
						case 2
							uialert(app.UIFigure,'Binaural TIN is not yet implemented','');
							app.RunButton.Enable = 'on';
							app.UIFigure.Pointer = 'arrow';
							close(app.Progressbar)
							return
					end
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 5 % Profile Analysis (see Lentz 2005)
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					ncomponents = str2double(params(4).ValueH.Value);
					dB_incr = str2double(params(5).ValueH.Value);
					app.nconditions = 2; % compare responses to 2 stimuli
					app.Condition(1).stimulus = Profile_Analysis(dur,rampdur,ncomponents,dB_incr,spl,app.Fs);
					app.Condition(2).stimulus = Profile_Analysis(dur,rampdur,ncomponents,-Inf,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 6 % Pinna Cues
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					fcenter = str2double(params(4).ValueH.Value);
					app.nconditions = 1;
					app.Condition(1).stimulus = artificial_pinna_notch(dur,rampdur,fcenter,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 7 % SAM Tone
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					carrier_freq = str2double(params(4).ValueH.Value);
					mod_freq = str2double(params(5).ValueH.Value);
					mod_depth = str2double(params(6).ValueH.Value);
					app.nconditions = 2;
					app.Condition(1).stimulus = SAM_Tone(dur,rampdur,carrier_freq,mod_freq,mod_depth,spl,app.Fs);
					app.Condition(2).stimulus = SAM_Tone(dur,rampdur,carrier_freq,mod_freq,-Inf,spl,app.Fs); % unmodulated tone
					% app.Condition(1).stimulus = SAM_Tone_residue(dur,rampdur,...
					% 	carrier_freq,mod_freq,mod_depth,stimdB,Fs,0); % SAM tone (choose harmonics)
					% app.Condition(2).stimulus = SAM_Tone_residue(dur,rampdur,...
					% 	carrier_freq,mod_freq,mod_depth,stimdB,Fs,40); % shifted SAM tone
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 8 % Complex Tone
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					f0 = str2double(params(4).ValueH.Value);
					ncomponents = str2double(params(5).ValueH.Value);
					include_fundmntl = logical(params(6).ValueH.Value);
					filter_type = params(7).ValueH.Value - 1;
					Wn_freq = sscanf(params(8).ValueH.Value,'%f').';
					app.nconditions = 1; % display response to only 1 stimulus
					app.Condition(1).stimulus = complex_tone(dur,rampdur,f0,ncomponents,filter_type,Wn_freq,...
						include_fundmntl,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = NaN;

				case 9 % Single Formant - Lyzenga & Horst's triangular spectrum
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					Fp = str2double(params(4).ValueH.Value); % Peak Freq of spectral envelope (Hz)
					F0 = str2double(params(5).ValueH.Value); % Fundamental freq (Hz)
					G = str2double(params(6).ValueH.Value); % spectral slope (dB/oct)
					app.nconditions = 1; % display response to only 1 stimulus
					app.Condition(1).stimulus = generate_single_formant(dur,rampdur,F0,Fp,G,spl,app.Fs)';
					app.Condition(1).dur = dur;
					app.Condition(2).dur = NaN;

				case 10 % Double Formant Klatt vowel
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					F0 = str2double(params(4).ValueH.Value); % Fundamental freq (Hz)
					formant_freqs = sscanf(params(5).ValueH.Value,'%f').'; % Vector of formant freqs (Hz)
					BWs = sscanf(params(6).ValueH.Value,'%f').'; % Vector of bandwidths for the formants (Hz)
					app.nconditions = 1; % display response to only 1 stimulus
					app.Condition(1).stimulus = klatt_vowel(dur,rampdur,F0,formant_freqs,BWs,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = NaN;

				case 11 % Schroeder Phase Complex Tone
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					f0 = str2double(params(4).ValueH.Value);
					ncomponents = str2double(params(5).ValueH.Value);
					C1value = str2double(params(6).ValueH.Value);
					C2value = str2double(params(7).ValueH.Value);
					include_fundmntl = logical(params(8).ValueH.Value);
					app.nconditions = 2; % display response to 2 stimuli
					dB_incr = -Inf; % This variable can be used for Schroeder masking simulations; turn it "off" for now
					% Note sign change of Cvalue.
					app.Condition(1).stimulus = schroeder(dur,rampdur,C1value,f0,ncomponents,dB_incr,include_fundmntl,spl,app.Fs);
					app.Condition(2).stimulus = schroeder(dur,rampdur,C2value,f0,ncomponents,dB_incr,include_fundmntl,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 12 % Noise-in-Notched Noise (Viemeister 1983)
					dur = str2double(params(1).ValueH.Value);
					rampdur = str2double(params(2).ValueH.Value);
					db_target_No = str2double(params(3).ValueH.Value); % dB SPL spectrum level for standard target noise
					db_increment_So = str2double(params(4).ValueH.Value); % dB increment for test target noise
					app.nconditions = 2; % compare responses to 2 stimuli
					% cf = str2double(stimParamEdit{stimIdx,3}.String); % Hz; center freq of stimulus (not neuron's CF)
					% delta = str2double(stimParamEdit{stimIdx,4}.String); % UNITS (Notch width/2)/CF
					% bw = str2double(stimParamEdit{stimIdx,5}.String); % Hz for each noise band
					app.Condition(1).stimulus = Noise_in_Notched_Noise(dur,...
						rampdur,db_target_No,db_increment_So,app.Fs); % test - target noise with No+So spectrum level
					app.Condition(2).stimulus = Noise_in_Notched_Noise(dur,...
						rampdur,db_target_No,-Inf,app.Fs); % standard - target noise with No spectrum level
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 13 % FM Tone
					spl = str2double(params(1).ValueH.Value);
					dur = str2double(params(2).ValueH.Value);
					rampdur = str2double(params(3).ValueH.Value);
					signalfreq = str2double(params(4).ValueH.Value);
					C1_params = sscanf(params(5).ValueH.Value,'%f').';
					C2_params = sscanf(params(6).ValueH.Value,'%f').';
					app.nconditions = 2;
					app.Condition(1).stimulus = FM_Tone(dur,rampdur,signalfreq,C1_params,spl,app.Fs);
					app.Condition(2).stimulus = FM_Tone(dur,rampdur,signalfreq,C2_params,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 14 % Forward Masking
					spl = str2double(params(1).ValueH.Value);
					mask_dur = str2double(params(2).ValueH.Value);
					mask_ramp = str2double(params(3).ValueH.Value);
					dur = str2double(params(4).ValueH.Value);
					probe_level = str2double(params(5).ValueH.Value);
					mask_freq = str2double(params(6).ValueH.Value);
					probe_freq = str2double(params(7).ValueH.Value);
					delay = str2double(params(8).ValueH.Value);
					app.nconditions = 2;
					% app.Condition(1) = Masker + probe; app.Condition(2) = Masker only
					[app.Condition(1).stimulus,app.Condition(2).stimulus] = Forward_masking_tones(mask_dur,mask_ramp,...
						dur,probe_level,mask_freq,probe_freq,delay,spl,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 15 % CMR Band widening
					dur = str2double(params(1).ValueH.Value);
					s_rampdur = str2double(params(2).ValueH.Value);
					freq = str2double(params(3).ValueH.Value);
					tone_level = str2double(params(4).ValueH.Value); % Tone level (E/No) ( see Evilsizer et al 2001 )
					bw = str2double(params(5).ValueH.Value); % This is calculated and displayed
					No = str2double(params(6).ValueH.Value); % Noise masker spectrum level (dB SPL / Hz)
					m_dur = str2double(params(7).ValueH.Value);
					m_rampdur = str2double(params(8).ValueH.Value);
					bw_mod = str2double(params(9).ValueH.Value);
					app.nconditions = 2; % compare responses to 2 stimuli
					% [mod noise plus tone,unmodulated noise-plus-tone]
					[app.Condition(1).stimulus,app.Condition(2).stimulus] = CMR_BW2(dur,m_dur,s_rampdur,m_rampdur,freq,bw,...
						bw_mod,No,tone_level,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

				case 16 % CMR Flanking Bands
					dur = str2double(params(1).ValueH.Value);
					rampdur = str2double(params(2).ValueH.Value);
					freq = str2double(params(3).ValueH.Value);
					No = str2double(params(4).ValueH.Value); % Noise masker spectrum level (dB SPL / Hz)
					tone_level = str2double(params(5).ValueH.Value); % Tone level (E/No) (see Evilsizer et al 2001)
					BW = str2double(params(6).ValueH.Value); % This is calculated and displayed
					% Check BW.
					if BW >= 0.4*freq
						uialert(app.UIFigure,...
							'Flank bandwidth must be less than Tone frequency.',...
							'Value Error')
						close(app.Progressbar)
						app.UIFigure.Pointer = 'arrow';
						return
					end
					app.nconditions = 2; % compare responses to 2 stimuli
					% [comod noise plus tone,codeviant noise-plus-tone]
					[app.Condition(1).stimulus,app.Condition(2).stimulus] = CMR_FB(dur,rampdur,freq,No,tone_level,BW,app.Fs);
					app.Condition(1).dur = dur;
					app.Condition(2).dur = dur;

					% case 17 % Example for user-supplied stimulus (STIM)
					% 	param1 = str2double(params(1).ValueH.Value);
					% 	param2 = str2double(params(1).ValueH.Value);
					% 	app.nconditions = 2; % compare responses to 2 stimuli
					% 	[app.Condition(1).stimulus,app.Condition(2).stimulus] = user_function(param1,param2,Fs);

					% case n % Template for addition of new stimulus that is not a wavfile
					% Get parameters from params as above.
					% Stimulus waveforms must be in scaled into pascals in stimulus
					%   function code.
					% Must pass sampling frequency (Fs) to stimulus code; stimulus
					%   level in dB, Duration, and on/off ramp durations, etc.
					% If only 1 stimulus waveform is to be used, set app.nconditions = 1
					%   and use app.Condition(1).stimulus (see noise example above).
					% app.Condition(1).stimulus may be a null or baseline stimulus to
					%   compare against (i.e., noise without a tone, a tone complex
					%   without an increment in the center, etc.)
					% app.Condition(1).stimulus = mystimulus(dur, rampdur, param1, param2, ..., param6, spl, Fs);
					% Then comparison stimulus should be placed in app.Condition(2):
					% app.Condition(2).stimulus = mystimulus(dur, rampdur, param1, param2, ..., param6, spl, Fs);
			end

			% Update and make sound playing controls visible.
			app.PlayDownloadSamplerateEditFieldValueChanged([])

			% Close progress bar.
			if ~app.StandardCheckBox.Value && ~app.WideCheckBox.Value
				app.Progressbar.Message = sprintf('Clearing standard plots…');
				app.clear_standard_plots()
				app.Progressbar.Message = sprintf('Clearing wide plots…');
				app.clear_wide_plots()
				close(app.Progressbar)
				app.UIFigure.Pointer = 'arrow';
				return
			end

			% Model Parameters
			minCF = str2double(char(app.CFEditLo.Value));
			maxCF = str2double(char(app.CFEditHi.Value));
			CF_num =str2double(char(app.numCFEdit.Value));
			fiber_num = str2double(char(app.num_fibers_edit.Value));
			app.CF_range = [minCF, maxCF];

			% Hearing loss in dB, to determine Cohc and Cihc for model using
			% function from Bruce and Zilany models, as in Bruce et al 2018 code.
			ag_dbloss = cellfun(@str2double,{app.OHCEdit.Value});
			ag_fs = [125 250 500 1e3 2e3 4e3 8e3]; % audiometric frequencies

			% Switch progress bar to determinate.
			app.Progressbar.Indeterminate = 'off';

			% Call Model and Plotting code
			UR_EAR_model_plots(app,ag_fs,ag_dbloss,fiber_num,CF_num)

			if app.Abort
				app.Abort = false;
				% Enable Run button, disable Abort button, set pointer back to 'arrow'.
				% app.RunButton.Enable = 'on';
				app.UIFigure.Pointer = 'arrow';
				return
			end

			% Compute analysis window times.
			if ~app.analysis_window_hold
				% Compute new analysis window and position lines.
				dur1 = app.Condition(1).dur;
				if isempty(app.Condition(2).stimulus)
					dur2 = NaN;
				else
					dur2 = app.Condition(2).dur;
				end
				dur = min([dur1,dur2]);
				app.aw_t = [0.1 0.9]*dur;

				app.analysis_window_lines(1).Value = app.aw_t(1);
				app.analysis_window_lines(2).Value = app.aw_t(2);

				% Update plots to reflect new analysis window.
				app.analysis_window_hold = false;
				app.HoldButton.Value = false;
				app.AnalysisWindowEditField.Value = app.aw_t(1);
				app.toEditField.Value = app.aw_t(2);
			end
			if app.StandardCheckBox.Value
				app.update_plots_with_selection()
			end

			% Set pointer back to 'arrow' and delete progress bar.
			app.UIFigure.Pointer = 'arrow';
			delete(app.Progressbar)

		end

		% Button pushed function: PlotButton
		function PlotButtonPushed(app, event)
			CF_entered = app.CFHzEditField.Value;
			[~,CFindex] = min(abs(log10(app.CFs) - log10(CF_entered)));
			CF_qp = app.CFs(CFindex);
			t_max = max([app.Condition.dur2]);

			make_normal_figure = false;
			if make_normal_figure && ~isdeployed
				figure

				% Make AN plots.
				for i = 1:app.nconditions
					subplot(3,1,i)
					data = app.Condition(i).an_sout_population(CFindex,:);
					t = (0:length(data)-1)/app.RsFs;
					plot(t,data,'Color',app.colors{i})
					xlim([0 t_max])
					title(sprintf('Condition %d: AN fiber, CF = %.4g Hz',i,CF_qp))
					xlabel('Time (s)')
					ylabel('Rate (spikes/s)')
				end

				% Make BE plot.
				subplot(3,1,3)
				for i = 1:app.nconditions
					data = app.Condition(i).BE_sout_population(CFindex,:);
					t = (0:length(data)-1)/app.RsFs;
					plot(t,data,'Color',app.colors{i})
					hold on
				end
				hold off
				xlim([0 t_max])
				title(sprintf('Both Conditions: IC BE model, CF = %.4g Hz',CF_qp))
				xlabel('Time (s)')
				ylabel('Rate (spikes/s)')
			else
				% Create cover for figure.
				fig = app.UIFigure;
				figpos = fig.Position;
				figpos(4) = 2140;
				cover_fig = cover_uifigure(fig,figpos);

				% Create dialog box panel.
				pos = app.Panel.Position + [20 20 -40 -40];
				qp_panel = uipanel(fig,...
					'Position',pos,...
					'Title','  Quick Plot',...
					'TitlePosition','lefttop',...
					'FontSize',14,...
					'FontWeight','bold',...
					'ForegroundColor',[0.25 0.25 0.25]);

				% Create tiled layout object.
				layout = tiledlayout(qp_panel,3,1);

				% Make AN plots.
				for i = 1:app.nconditions
					ax = nexttile(layout,i);
					data = app.Condition(i).an_sout_population(CFindex,:);
					t = (0:length(data)-1)/app.RsFs;
					plot(ax,t,data,'Color',app.colors{i})
					xlim(ax,[0 t_max])
					title(ax,sprintf('Condition %d: AN fiber, CF = %.4g Hz',i,CF_qp))
					xlabel(ax,'Time (s)')
					ylabel(ax,'Rate (spikes/s)')
					app.make_exportable(ax)
					ax.Units = 'pixels';
				end

				% Make BE plot.
				ax = nexttile(layout,3);
				for i = 1:app.nconditions
					data = app.Condition(i).BE_sout_population(CFindex,:);
					t = (0:length(data)-1)/app.RsFs;
					plot(ax,t,data,'Color',app.colors{i})
					hold(ax,'on')
				end
				hold(ax,'off')
				xlim(ax,[0 t_max])
				if app.nconditions == 1
					title(ax,sprintf('Condition 1: IC BE model, CF = %.4g Hz',CF_qp))
				else
					title(ax,sprintf('Conditions 1–%d: IC BE model, CF = %.4g Hz',app.nconditions,CF_qp))
				end
				xlabel(ax,'Time (s)')
				ylabel(ax,'Rate (spikes/s)')
				app.make_exportable(ax)
				ax.Units = 'pixels';

				% Create Close button.
				uibutton(qp_panel,'push',...
					'Position',[pos(3)/2-50, 10, 100, 22],...
					'Text','Close',...
					'FontSize',12,...
					'ButtonPushedFcn',@OKButtonPushed);

				% Show the figure after all components are created.
				qp_panel.Visible = 'on';

			end

			% Button pushed function: CloseButton
			function OKButtonPushed(~,~)
				% Delete the panel and gray background.
				delete(qp_panel)
				delete(cover_fig)
			end


			function h = cover_uifigure(fig,pos)
				% Create a gray background for a dialog box that covers the
				% whole figure.
				color = [0.1 0.1 0.1];
				alpha = 0.5;

				html_fmt = ['<html>\n',...
					'<head>\n',...
					'<style>\n',...
					'div {\n',...
					'background: rgb(%d, %d, %d, %g);\n',...
					'width: %dpx;\n',...
					'height: %dpx;\n',...
					'}\n',...
					'</style>\n',...
					'</head>\n',...
					'<body><div></div></body>\n',...
					'</html>\n'];

				colori = round(255*color);
				html = sprintf(html_fmt,colori,alpha,pos(3:4));

				h = uihtml(fig,'Position',[1 1 pos(3:4)],...
					'HTMLSource',html);
			end


		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'UR EAR 2022a';
			app.StimulusTypeDropDownLabel = uilabel(app.UIFigure);
			app.StimulusTypeDropDownLabel.Text = 'Stimulus Type';
			app.StimulusTypeDropDown = uidropdown(app.UIFigure);
			app.StimulusTypeDropDown.Items = {'Audio File', 'Noise Band (Edge Pitch)', 'Notched Noise', 'Tone in Noise', 'Profile Analysis', 'Pinna Cues', 'SAM Tone', 'Complex Tone', 'Single Formant', 'Double Formant', 'Schroeder Phase', 'Noise-in-Notched Noise', 'FM Tone', 'Forward Masking', 'CMR Band Widen', 'CMR Flank Band'};
			app.StimulusTypeDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
			app.StimulusTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @select_stim_type, true);
			app.StimulusTypeDropDown.Value = 1;
			app.StimulusParametersPanel = uipanel(app.UIFigure);
			app.StimulusParametersPanel.Title = 'Stimulus Parameters';
			app.StimulusParametersPanel.Visible = 'off';
			app.ModelParametersPanel = uipanel(app.UIFigure);
			app.ModelParametersPanel.Title = 'Model Parameters   (Sample rate = 100 kHz)';
			app.RunPanel = uipanel(app.UIFigure);
			app.RunPanel.Title = 'Run';
			app.RunButton = uibutton(app.RunPanel, 'push');
			app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
			app.RunButton.Enable = 'off';
			app.RunButton.Text = 'Run';
			app.AutoscrollButtonGroup = uibuttongroup(app.RunPanel);
			app.AutoscrollButtonGroup.Title = 'Auto scroll';
			app.OffButton = uiradiobutton(app.AutoscrollButtonGroup);
			app.OffButton.Text = 'Off';
			app.toStandardPlotsButton = uiradiobutton(app.AutoscrollButtonGroup);
			app.toStandardPlotsButton.Text = 'to Standard Plots';
			app.toStandardPlotsButton.Value = true;
			app.toWidePlotsButton = uiradiobutton(app.AutoscrollButtonGroup);
			app.toWidePlotsButton.Text = 'to Wide Plots';
			app.PlotstoshowPanel = uipanel(app.RunPanel);
			app.PlotstoshowPanel.Title = 'Plots to show';
			app.StandardCheckBox = uicheckbox(app.PlotstoshowPanel);
			app.StandardCheckBox.Text = 'Standard';
			app.StandardCheckBox.Value = true;
			app.WideCheckBox = uicheckbox(app.PlotstoshowPanel);
			app.WideCheckBox.Text = 'Wide';
			app.WideCheckBox.Value = true;
			app.Panel = uipanel(app.UIFigure);
			app.AnalysisWindowEditFieldLabel = uilabel(app.Panel);
			app.AnalysisWindowEditFieldLabel.Text = 'Analysis Window:';
			app.AnalysisWindowEditField = uieditfield(app.Panel, 'numeric');
			app.AnalysisWindowEditField.ValueDisplayFormat = '%.3g';
			app.AnalysisWindowEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisWindowEditFieldValueChanged, true);
			app.toEditFieldLabel = uilabel(app.Panel);
			app.toEditFieldLabel.Text = 'to';
			app.toEditField = uieditfield(app.Panel, 'numeric');
			app.toEditField.ValueDisplayFormat = '%.3g';
			app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
			app.secLabel = uilabel(app.Panel);
			app.secLabel.Text = 'sec.';
			app.ResetButton = uibutton(app.Panel, 'push');
			app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
			app.ResetButton.Text = 'Reset';
			app.HelpButton = uibutton(app.Panel, 'push');
			app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
			app.HelpButton.Text = 'Help…';
			app.HoldButton = uibutton(app.Panel, 'state');
			app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
			app.HoldButton.Text = 'Hold';
			app.QuickPlotofANICatoneCFPanel = uipanel(app.Panel);
			app.QuickPlotofANICatoneCFPanel.Title = 'Quick Plot of AN & IC at one CF';
			app.CFHzEditFieldLabel = uilabel(app.QuickPlotofANICatoneCFPanel);
			app.CFHzEditFieldLabel.Text = 'CF (Hz)';
			app.CFHzEditField = uieditfield(app.QuickPlotofANICatoneCFPanel, 'numeric');
			app.CFHzEditField.Value = 500;
			app.PlotButton = uibutton(app.QuickPlotofANICatoneCFPanel, 'push');
			app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
			app.PlotButton.Text = 'Plot';
			app.WidePlotsPanel = uipanel(app.UIFigure);
			app.Label = uilabel(app.UIFigure);
			app.Label.Text = '';
			app.Label_2 = uilabel(app.UIFigure);
			app.Label_2.Text = '';
			app.ManualButton = uibutton(app.UIFigure, 'push');
			app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
			app.ManualButton.Text = 'Manual…';
			app.FAQButton = uibutton(app.UIFigure, 'push');
			app.FAQButton.ButtonPushedFcn = createCallbackFcn(app, @FAQButtonPushed, true);
			app.FAQButton.Text = 'FAQ…';
			app.ContactButton = uibutton(app.UIFigure, 'push');
			app.ContactButton.ButtonPushedFcn = createCallbackFcn(app, @ContactButtonPushed, true);
			app.ContactButton.Text = 'Contact…';
			app.Label_3 = uilabel(app.UIFigure);
			app.Label_3.Text = ' ';
			app.aLabel = uilabel(app.UIFigure);
			app.aLabel.Text = '2022a';
			app.SupportLabel = uilabel(app.UIFigure);
			app.SupportLabel.Text = 'Supported by NIDCD-R01-001641-Supplement';
			app.UREARLabel = uilabel(app.UIFigure);
			app.UREARLabel.Text = 'UR EAR';
			app.WidePlotsLabel = uilabel(app.UIFigure);
			app.WidePlotsLabel.Text = 'Wide Plots';
			app.StandardPlotsLabel = uilabel(app.UIFigure);
			app.StandardPlotsLabel.Text = 'Standard Plots';
			app.ClickWideLabel = uilabel(app.UIFigure);
			app.ClickWideLabel.Text = 'Click on any plot to toggle conditions for all.';
			app.ClickNarrowLabel = uilabel(app.UIFigure);
			app.ClickNarrowLabel.Text = 'Click on a plot to toggle conditions.';
			app.C2Label = uilabel(app.UIFigure);
			app.C2Label.Text = 'C2:';
			app.C1Label = uilabel(app.UIFigure);
			app.C1Label.Text = 'C1:';
			app.PlayDownloadSoundsLabel = uilabel(app.UIFigure);
			app.PlayDownloadSoundsLabel.Tooltip = {''};
			app.PlayDownloadSoundsLabel.Text = 'Play/Download Sounds';
			app.PlayDownloadSamplerateEditFieldLabel = uilabel(app.UIFigure);
			app.PlayDownloadSamplerateEditFieldLabel.Text = 'Play/Download Sample rate';
			app.PlayDownloadSamplerateEditField = uieditfield(app.UIFigure, 'numeric');
			app.PlayDownloadSamplerateEditField.ValueDisplayFormat = '%.0f';
			app.PlayDownloadSamplerateEditField.ValueChangedFcn = createCallbackFcn(app, @PlayDownloadSamplerateEditFieldValueChanged, true);
			app.PlayDownloadSamplerateEditField.Tooltip = {'Sample rate (samples/sec) for dowloaded files and played sounds'};
			app.PlayDownloadSamplerateEditField.Value = 48000;
			app.DownloadfromcontrolsLabel = uilabel(app.UIFigure);
			app.DownloadfromcontrolsLabel.Text = 'Download from media player controls ➔';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = UR_EAR_2022a(varargin)

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end